classdef simulacie_epidemiologickych_modelov < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        EditField_13Label    matlab.ui.control.Label
        EditField_13         matlab.ui.control.NumericEditField
        EditField_12Label    matlab.ui.control.Label
        EditField_12         matlab.ui.control.NumericEditField
        EditField_11Label    matlab.ui.control.Label
        EditField_11         matlab.ui.control.NumericEditField
        EditField_10Label    matlab.ui.control.Label
        EditField_10         matlab.ui.control.NumericEditField
        EditField_9Label     matlab.ui.control.Label
        EditField_9          matlab.ui.control.NumericEditField
        EditField_8Label     matlab.ui.control.Label
        EditField_8          matlab.ui.control.NumericEditField
        EditField_7Label     matlab.ui.control.Label
        EditField_7          matlab.ui.control.NumericEditField
        EditField_6Label     matlab.ui.control.Label
        EditField_6          matlab.ui.control.NumericEditField
        EditField_5Label     matlab.ui.control.Label
        EditField_5          matlab.ui.control.NumericEditField
        EditField_4Label     matlab.ui.control.Label
        EditField_4          matlab.ui.control.NumericEditField
        EditField_3Label     matlab.ui.control.Label
        EditField_2Label     matlab.ui.control.Label
        EditField_3          matlab.ui.control.NumericEditField
        EditField_2          matlab.ui.control.NumericEditField
        EditFieldLabel       matlab.ui.control.Label
        EditField            matlab.ui.control.NumericEditField
        EEditField_30        matlab.ui.control.NumericEditField
        EEditField_30Label   matlab.ui.control.Label
        EEditField_29        matlab.ui.control.NumericEditField
        EEditField_29Label   matlab.ui.control.Label
        EEditField_28        matlab.ui.control.NumericEditField
        EEditField_28Label   matlab.ui.control.Label
        EEditField_27        matlab.ui.control.NumericEditField
        EEditField_27Label   matlab.ui.control.Label
        EEditField_26        matlab.ui.control.NumericEditField
        EEditField_26Label   matlab.ui.control.Label
        EEditField_25        matlab.ui.control.NumericEditField
        EEditField_25Label   matlab.ui.control.Label
        EEditField_24        matlab.ui.control.NumericEditField
        EEditField_24Label   matlab.ui.control.Label
        EEditField_23        matlab.ui.control.NumericEditField
        EEditField_23Label   matlab.ui.control.Label
        EEditField_22        matlab.ui.control.NumericEditField
        EEditField_22Label   matlab.ui.control.Label
        EEditField_21        matlab.ui.control.NumericEditField
        EEditField_21Label   matlab.ui.control.Label
        EEditField_20        matlab.ui.control.NumericEditField
        EEditField_20Label   matlab.ui.control.Label
        EEditField_19        matlab.ui.control.NumericEditField
        EEditField_19Label   matlab.ui.control.Label
        EEditField_18        matlab.ui.control.NumericEditField
        EEditField_18Label   matlab.ui.control.Label
        EEditField_17        matlab.ui.control.NumericEditField
        EEditField_17Label   matlab.ui.control.Label
        EEditField_16        matlab.ui.control.NumericEditField
        EEditField_16Label   matlab.ui.control.Label
        EEditField_15        matlab.ui.control.NumericEditField
        EEditField_15Label   matlab.ui.control.Label
        EEditField_14        matlab.ui.control.NumericEditField
        EEditField_14Label   matlab.ui.control.Label
        EEditField_13        matlab.ui.control.NumericEditField
        EEditField_13Label   matlab.ui.control.Label
        EEditField_12        matlab.ui.control.NumericEditField
        EEditField_12Label   matlab.ui.control.Label
        EEditField_11        matlab.ui.control.NumericEditField
        EEditField_11Label   matlab.ui.control.Label
        EEditField_10        matlab.ui.control.NumericEditField
        EEditField_10Label   matlab.ui.control.Label
        EEditField_9         matlab.ui.control.NumericEditField
        EEditField_9Label    matlab.ui.control.Label
        EEditField_8         matlab.ui.control.NumericEditField
        EEditField_8Label    matlab.ui.control.Label
        EEditField_7         matlab.ui.control.NumericEditField
        EEditField_7Label    matlab.ui.control.Label
        EEditField_6         matlab.ui.control.NumericEditField
        EEditField_6Label    matlab.ui.control.Label
        EEditField_5         matlab.ui.control.NumericEditField
        EEditField_5Label    matlab.ui.control.Label
        EEditField_4         matlab.ui.control.NumericEditField
        EEditField_4Label    matlab.ui.control.Label
        EEditField_3         matlab.ui.control.NumericEditField
        EEditField_3Label    matlab.ui.control.Label
        EEditField_2         matlab.ui.control.NumericEditField
        EEditField_2Label    matlab.ui.control.Label
        EEditField           matlab.ui.control.NumericEditField
        EEditFieldLabel      matlab.ui.control.Label
        PrepotaButton        matlab.ui.control.Button
        DropDownSelectModel  matlab.ui.control.DropDown
        ZvotemodelLabel      matlab.ui.control.Label
        UIAxes_generation2   matlab.ui.control.UIAxes
        UIAxes_generation1   matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

        
            methods (Access = private)
        
                function model1(app)
        
        
                    Time = app.EEditField_12.Value;
                    beta = app.EEditField_6.Value;
                    p = app.EEditField_7.Value;
                    u = app.EEditField_8.Value;
                    v1 = app.EEditField_9.Value;
                    v2 = app.EEditField_10.Value;
                    y = app.EEditField_11.Value;
                    L1_0 = app.EEditField_2.Value;
                    L2_0 = app.EEditField_3.Value;
                    I_0 = app.EEditField_4.Value;
                    R_0 = app.EEditField_5.Value;
                    S_0 = app.EEditField.Value;
                    N=I_0+L1_0+L2_0+R_0+S_0;
        
                    f = @(t,x) [u*N-((beta*x(1)*x(4))/N)-u*x(1);
                        p*((beta*x(1)*x(4))/N)-(v1+u)*x(2);
                        (1-p)*((beta*x(1)*x(4))/N)-(v2+u)*x(3)
                        v1*x(2)+v2*x(3)-(y+u)*x(4);
                        y*x(4)-u*x(5)];
                    [t,xa] = ode45(f,[0 Time],[S_0 L1_0 L2_0 I_0 R_0]);
        
                    Rep_0 =  (beta*p*v1)/((u+v1)*(u+y))+(beta*(1-p)*v2)/((u+v2)*(u+y));
        
                    E_S = xa(end,1);
                    E_L1 = xa(end,2);
                    E_L2 = xa(end,3);
                    E_I = xa(end,4);
                    E_R = xa(end,5);
        
                    title(app.UIAxes,'Model č. 1','interpreter','latex')
        
                    plot(app.UIAxes,t,xa(:,1),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,4),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,5),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)')
                    hold(app.UIAxes,'on')
        
                    yline(app.UIAxes,E_S,'Color',[0.0745098039215686 0.623529411764706 1],'LineWidth',1,'LineStyle',':','DisplayName',' $$S\infty  $$ ')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_L1,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_L2,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_I,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_R,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')
                    hold(app.UIAxes,'on')


                     title(app.UIAxes_generation1,'Model č. 1 latecia 1','interpreter','latex')
                      hold(app.UIAxes_generation1,'on')
               
                   
                    yline(app.UIAxes_generation1,E_L1,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                     hold(app.UIAxes_generation1,'on')
                    %yline(app.UIAxes_generation1,E_I,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                     %hold(app.UIAxes_generation1,'on')
                   % yline(app.UIAxes_generation1,E_R,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')
                    % hold(app.UIAxes_generation1,'on')

                    plot(app.UIAxes_generation1,t,xa(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)')
                    hold(app.UIAxes_generation1,'on')
                    %plot(app.UIAxes_generation1,t,xa(:,4),'Color',[1  0  0],...
                      %  'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    %hold(app.UIAxes_generation1,'on')
                    %plot(app.UIAxes_generation1,t,xa(:,5),'Color',[0.71765     0.27451           1],...
                     %   'LineWidth',2,'DisplayName','Priebeh funkcie R(t)')
                   % hold(app.UIAxes_generation1,'on')


                    title(app.UIAxes_generation2,'Model č. 1 latencia 2','interpreter','latex')
                     hold(app.UIAxes_generation2,'on')
               
                    yline(app.UIAxes_generation2,E_L2,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                    hold(app.UIAxes_generation2,'on')

                   % yline(app.UIAxes_generation2,E_I,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                   % hold(app.UIAxes_generation2,'on')
                   % yline(app.UIAxes_generation2,E_R,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')
        

                    plot(app.UIAxes_generation2,t,xa(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)')
                    hold(app.UIAxes_generation2,'on')
                    %plot(app.UIAxes_generation2,t,xa(:,4),'Color',[1  0  0],...
                    %    'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    %hold(app.UIAxes_generation2,'on')
                    %plot(app.UIAxes_generation2,t,xa(:,5),'Color',[0.71765     0.27451           1],...
                     %   'LineWidth',2,'DisplayName','Priebeh funkcie R(t)')
                   % hold(app.UIAxes_generation2,'on')


                    app.EditField.Value = round(Rep_0,2);
                    app.EditFieldLabel.Text = "$\mathcal{R}_0$ =";
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField.Visible = 'on';
        
                    app.EditField_2Label.Text = "$S^\infty =$";
                    app.EditField_2.Value = double(round(E_S));
                    app.EditField_2Label.Visible = 'on';
                    app.EditField_2.Visible = 'on';
        
                    app.EditField_3Label.Text = "$L1^\infty =$";
                    app.EditField_3.Value = double(round(E_L1));
                    app.EditField_3Label.Visible = 'on';
                    app.EditField_3.Visible = 'on';
        
                    app.EditField_4Label.Text = "$L2^\infty =$";
                    app.EditField_4.Value = double(round(E_L2));
                    app.EditField_4Label.Visible = 'on';
                    app.EditField_4.Visible = 'on';
        
                    app.EditField_5Label.Text = "$I^\infty =$";
                    app.EditField_5.Value = double(round(E_I));
                    app.EditField_5Label.Visible = 'on';
                    app.EditField_5.Visible = 'on';
        
                    app.EditField_6Label.Text = "$R^\infty =$";
                    app.EditField_6.Value = double(round(E_R));
                    app.EditField_6Label.Visible = 'on';
                    app.EditField_6.Visible = 'on';
        
                    
                    app.EditField_7Label.Visible = 'off';
                    app.EditField_7.Visible = 'off';
        
                    app.EditField_8Label.Visible = 'off';
                    app.EditField_8.Visible = 'off';
        
                    app.EditField_9Label.Visible = 'off';
                    app.EditField_9.Visible = 'off';
        
                    app.EditField_10Label.Visible = 'off';
                    app.EditField_10.Visible = 'off';
        
                    app.EditField_11Label.Visible = 'off';
                    app.EditField_11.Visible = 'off';
        
        
                    %legend(app.UIAxes,{'$S(t)$','$L_1(t)$','$L_2(t)$','$I(t)$','$R(t)$'},...
                      %  'Location','northeast','NumColumns',2,'Interpreter','latex')
        
                end
        
                function model3(app)
        
        
                    Time = app.EEditField_18.Value;
                    p = app.EEditField_8.Value;
        
                    beta11 = app.EEditField_13.Value;
                    beta12 = app.EEditField_14.Value;
                    beta21 = app.EEditField_15.Value;
                    beta22 = app.EEditField_16.Value;
        
                    u = app.EEditField_17.Value;
        
                    v1 = app.EEditField_9.Value;
                    v2 = app.EEditField_10.Value;
        
                    y1 = app.EEditField_11.Value;
                    y2 = app.EEditField_12.Value;
        
                    L1_0 = app.EEditField_2.Value;
                    L2_0 = app.EEditField_3.Value;
        
                    I1_0 = app.EEditField_4.Value;
                    I2_0 = app.EEditField_5.Value;
        
                    R1_0 =app.EEditField_6.Value;
                    R2_0 =app.EEditField_7.Value;
        
        
                    N = app.EEditField.Value;
        
        
                    S1_0 = (p*N)-(I1_0+L1_0+R1_0);
                    S2_0 = ((1-p)*N)-(I2_0+L2_0+R2_0);
        
        
                    f = @(t,x) [p*u*N-((beta11*x(1)*x(5))/N)-((beta12*x(1)*x(6))/N)-u*x(1);
                        (1-p)*u*N-((beta21*x(2)*x(5))/N)-((beta22*x(2)*x(6))/N)-u*x(2);
                        ((beta11*x(1)*x(5))/N)+((beta12*x(1)*x(6))/N)-(v1+u)*x(3);
                        ((beta21*x(2)*x(5))/N)+((beta22*x(2)*x(6))/N)-(v2+u)*x(4);
                        v1*x(3)-(y1+u)*x(5);
                        v2*x(4)-(y2+u)*x(6);
                        y1*x(5)-u*x(7);
                        y2*x(6)-u*x(8);
                        ];
                    [t,xa] = ode45(f,[0 Time],[S1_0 S2_0 L1_0 L2_0 I1_0 I2_0 R1_0 R2_0]);
        
                    Rep_0 =  (beta11*p*v1)/((u+v1)*(u+y1))+(beta22*(1-p)*v2)/((u+v2)*(u+y2));
        
                    E_S1 = xa(end,1);
                    E_S2 = xa(end,2);
                    E_L1 = xa(end,3);
                    E_L2 = xa(end,4);
                    E_I1 = xa(end,5);
                    E_I2 = xa(end,6);
                    E_R1 = xa(end,7);
                    E_R2 = xa(end,8);
        
                    title(app.UIAxes,'Model č. 3','interpreter','latex')
        
                    plot(app.UIAxes,t,xa(:,1),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,2),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie E(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,4),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie E(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,5),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,6),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,7),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,8),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
        
                    yline(app.UIAxes,E_S1,'Color',[0.0745098039215686 0.623529411764706 1],'LineWidth',1,'LineStyle',':','DisplayName',' $$S\infty  $$ ')
                   hold(app.UIAxes_generation1,'on')
                    yline(app.UIAxes,E_S2,'Color',[0.0745098039215686 0.623529411764706 1],'LineWidth',1,'LineStyle',':','DisplayName',' $$S\infty  $$ ')
                    hold(app.UIAxes_generation1,'on')
                    yline(app.UIAxes,E_L1,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                    hold(app.UIAxes_generation1,'on')
                    yline(app.UIAxes,E_L2,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                   hold(app.UIAxes_generation1,'on')
                    yline(app.UIAxes,E_I1,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                   hold(app.UIAxes_generation1,'on')
                    yline(app.UIAxes,E_I2,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                   hold(app.UIAxes_generation1,'on')
                    yline(app.UIAxes,E_R1,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')
                   hold(app.UIAxes_generation1,'on')
                    yline(app.UIAxes,E_R2,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')


                    title(app.UIAxes_generation1,'Model č. 3 populácia 1','interpreter','latex')
                    hold(app.UIAxes_generation1,'on')

                     yline(app.UIAxes_generation1,E_S1,'Color',[0.0745098039215686 0.623529411764706 1],'LineWidth',1,'LineStyle',':','DisplayName',' $$S\infty  $$ ')
                    hold(app.UIAxes_generation1,'on')
                     yline(app.UIAxes_generation1,E_L1,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                    hold(app.UIAxes_generation1,'on')
                     yline(app.UIAxes_generation1,E_I1,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                    hold(app.UIAxes_generation1,'on')
                     yline(app.UIAxes_generation1,E_R1,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')


                    plot(app.UIAxes_generation1,t,xa(:,1),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)')
                    hold(app.UIAxes_generation1,'on')
                   
                    plot(app.UIAxes_generation1,t,xa(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie E(t)')
                    hold(app.UIAxes_generation1,'on')
                   
                    plot(app.UIAxes_generation1,t,xa(:,5),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    hold(app.UIAxes_generation1,'on')
                   
                    plot(app.UIAxes_generation1,t,xa(:,7),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle','--')
                    hold(app.UIAxes_generation1,'on')


                        title(app.UIAxes_generation2,'Model č. 3 populácia 2','interpreter','latex')
                     hold(app.UIAxes_generation2,'on')
                    yline(app.UIAxes_generation2,E_S2,'Color',[0.0745098039215686 0.623529411764706 1],'LineWidth',1,'LineStyle',':','DisplayName',' $$S\infty  $$ ')
                     hold(app.UIAxes_generation2,'on')
                    yline(app.UIAxes_generation2,E_L2,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                     hold(app.UIAxes_generation2,'on')
                    yline(app.UIAxes_generation2,E_I2,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                    hold(app.UIAxes_generation2,'on')
                    yline(app.UIAxes_generation2,E_R2,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')


                    plot(app.UIAxes_generation2,t,xa(:,2),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                   
                    plot(app.UIAxes_generation2,t,xa(:,4),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie E(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                   
                    plot(app.UIAxes_generation2,t,xa(:,6),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                   
                    plot(app.UIAxes_generation2,t,xa(:,8),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')


                    app.EditField.Value = round(Rep_0,2);
                    app.EditFieldLabel.Text = "$\mathcal{R}_0$ =";
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField.Visible = 'on';
        
                    app.EditField_2Label.Text = "$S1^\infty =$";
                    app.EditField_2.Value = double(round(E_S1));
                    app.EditField_2Label.Visible = 'on';
                    app.EditField_2.Visible = 'on';
        
                    app.EditField_3Label.Text = "$S2^\infty =$";
                    app.EditField_3.Value = double(round(E_S2));
                    app.EditField_3Label.Visible = 'on';
                    app.EditField_3.Visible = 'on';
        
                    app.EditField_4Label.Text = "$L1^\infty =$";
                    app.EditField_4.Value = double(round(E_L1));
                    app.EditField_4Label.Visible = 'on';
                    app.EditField_4.Visible = 'on';
        
                    app.EditField_5Label.Text = "$L2^\infty =$";
                    app.EditField_5.Value = double(round(E_L2));
                    app.EditField_5Label.Visible = 'on';
                    app.EditField_5.Visible = 'on';
        
                    app.EditField_6Label.Text = "$I1^\infty =$";
                    app.EditField_6.Value = double(round(E_I1));
                    app.EditField_6Label.Visible = 'on';
                    app.EditField_6.Visible = 'on';
        
                    app.EditField_7Label.Text = "$I2^\infty =$";
                    app.EditField_7.Value = double(round(E_I2));
                    app.EditField_7Label.Visible = 'on';
                    app.EditField_7.Visible = 'on';
        
                    app.EditField_8Label.Text = "$R1^\infty =$";
                    app.EditField_8.Value = double(round(E_R1));
                    app.EditField_8Label.Visible = 'on';
                    app.EditField_8.Visible = 'on';
        
                    app.EditField_9Label.Text = "$R2^\infty =$";
                    app.EditField_9.Value = double(round(E_R2));
                    app.EditField_9Label.Visible = 'on';
                    app.EditField_9.Visible = 'on';
        
                    app.EditField_10Label.Visible = 'off';
                    app.EditField_10.Visible = 'off';
        
                    app.EditField_11Label.Visible = 'off';
                    app.EditField_11.Visible = 'off';


                    box(app.UIAxes,'on')
                    hold(app.UIAxes,'on')
        
                    %legend(app.UIAxes,{'$S_1(t)$','$S_2(t)$','$L_1(t)$','$L_2(t)$','$I_1(t)$','$I_2(t)$','$R_1(t)$','$R_2(t)$'},...
                     %   'Location','northeast','NumColumns',2,'Interpreter','latex')
        
                end
        
                function model2(app)
        
        
                    N1= app.EEditField.Value;
                    N2= app.EEditField_2.Value;
        
                    Time = app.EEditField_16.Value;
        
                    beta1 = app.EEditField_13.Value;
                    beta2 = app.EEditField_14.Value;
        
                    u = app.EEditField_15.Value;
        
                    v1 = app.EEditField_9.Value;
                    v2 = app.EEditField_10.Value;
        
                    y1 = app.EEditField_11.Value;
                    y2 = app.EEditField_12.Value;
        
                    L1_0 = app.EEditField_3.Value;
                    L2_0 = app.EEditField_4.Value;
        
                    I1_0 = app.EEditField_5.Value;
                    I2_0 = app.EEditField_6.Value;
        
                    R1_0 =app.EEditField_7.Value;
                    R2_0 =app.EEditField_8.Value;
        
                    S1_0 = N1-(I1_0+L1_0+R1_0);
                    S2_0 = N2-(I2_0+L2_0+R2_0);
        
        
                    f = @(t,x) [
                        u*N1-((beta1*x(1)*x(6))/N2)-u*x(1);
                        u*N2-((beta2*x(2)*x(5))/N1)-u*x(2);
                        ((beta1*x(1)*x(6))/N2)-(v1+u)*x(3);
                        ((beta2*x(2)*x(5))/N1)-(v2+u)*x(4);
                        v1*x(3)-(y1+u)*x(5);
                        v2*x(4)-(y2+u)*x(6);
                        y1*x(5)-u*x(7);
                        y2*x(6)-u*x(8);
                        ];
                    [t,xa] = ode45(f,[0 Time],[S1_0 S2_0 L1_0 L2_0 I1_0 I2_0 R1_0 R2_0]);
        
                    Rep_0 =  (beta1*beta2*v2*v1)/((u+v2)*(u+y2)*(u+v1)*(u+y1));
        
                    E_S1 = xa(end,1);
                    E_S2 = xa(end,2);
                    E_L1 = xa(end,3);
                    E_L2 = xa(end,4);
                    E_I1 = xa(end,5);
                    E_I2 = xa(end,6);
                    E_R1 = xa(end,7);
                    E_R2 = xa(end,8);
        
        
                    title(app.UIAxes,'Model č. 2','interpreter','latex')
        
                    plot(app.UIAxes,t,xa(:,1),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,2),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,4),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,5),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,6),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
        
                    plot(app.UIAxes,t,xa(:,7),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,8),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle','--')
                    hold(app.UIAxes,'on')
        
                    yline(app.UIAxes,E_S1,'Color',[0.0745098039215686 0.623529411764706 1],'LineWidth',1,'LineStyle',':','DisplayName',' $$S\infty  $$ ')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_S2,'Color',[0.0745098039215686 0.623529411764706 1],'LineWidth',1,'LineStyle',':','DisplayName',' $$S\infty  $$ ')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_L1,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_L2,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_I1,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_I2,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                    hold(app.UIAxes,'on')
                    yline(app.UIAxes,E_R1,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')
                    yline(app.UIAxes,E_R2,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')


                    title(app.UIAxes_generation1,'Model č. 2 populácia 1','interpreter','latex')
                    hold(app.UIAxes_generation1,'on')

                     yline(app.UIAxes_generation1,E_S1,'Color',[0.0745098039215686 0.623529411764706 1],'LineWidth',1,'LineStyle',':','DisplayName',' $$S\infty  $$ ')
                    hold(app.UIAxes_generation1,'on')
                     yline(app.UIAxes_generation1,E_L1,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                    hold(app.UIAxes_generation1,'on')
                     yline(app.UIAxes_generation1,E_I1,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                    hold(app.UIAxes_generation1,'on')
                     yline(app.UIAxes_generation1,E_R1,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')


                    plot(app.UIAxes_generation1,t,xa(:,1),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)')
                    hold(app.UIAxes_generation1,'on')
                   
                    plot(app.UIAxes_generation1,t,xa(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie E(t)')
                    hold(app.UIAxes_generation1,'on')
                   
                    plot(app.UIAxes_generation1,t,xa(:,5),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    hold(app.UIAxes_generation1,'on')
                   
                    plot(app.UIAxes_generation1,t,xa(:,7),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle','--')
                    hold(app.UIAxes_generation1,'on')


                        title(app.UIAxes_generation2,'Model č. 2 populácia 2','interpreter','latex')
                     hold(app.UIAxes_generation2,'on')
                    yline(app.UIAxes_generation2,E_S2,'Color',[0.0745098039215686 0.623529411764706 1],'LineWidth',1,'LineStyle',':','DisplayName',' $$S\infty  $$ ')
                     hold(app.UIAxes_generation2,'on')
                    yline(app.UIAxes_generation2,E_L2,'Color',[1           1    0.066667],'LineWidth',1,'LineStyle',':','DisplayName',' $$E\infty  $$ ')
                     hold(app.UIAxes_generation2,'on')
                    yline(app.UIAxes_generation2,E_I2,'Color',[1  0  0],'LineWidth',1,'LineStyle',':','DisplayName','$$I\infty $$')
                    hold(app.UIAxes_generation2,'on')
                    yline(app.UIAxes_generation2,E_R2,'Color',[0.71765     0.27451           1],'LineWidth',1,'LineStyle',':','DisplayName','$$R\infty  $$')


                    plot(app.UIAxes_generation2,t,xa(:,2),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                   
                    plot(app.UIAxes_generation2,t,xa(:,4),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie E(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                   
                    plot(app.UIAxes_generation2,t,xa(:,6),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                   
                    plot(app.UIAxes_generation2,t,xa(:,8),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')


                    app.EditField.Value = round(Rep_0,2);
                    app.EditFieldLabel.Text = "$\mathcal{R}_0$ =";
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField.Visible = 'on';
        
                    app.EditField_2Label.Text = "$S1^\infty =$";
                    app.EditField_2.Value = double(round(E_S1));
                    app.EditField_2Label.Visible = 'on';
                    app.EditField_2.Visible = 'on';
        
                    app.EditField_3Label.Text = "$S2^\infty =$";
                    app.EditField_3.Value = double(round(E_S2));
                    app.EditField_3Label.Visible = 'on';
                    app.EditField_3.Visible = 'on';
        
                    app.EditField_4Label.Text = "$L1^\infty =$";
                    app.EditField_4.Value = double(round(E_L1));
                    app.EditField_4Label.Visible = 'on';
                    app.EditField_4.Visible = 'on';
        
                    app.EditField_5Label.Text = "$L2^\infty =$";
                    app.EditField_5.Value = double(round(E_L2));
                    app.EditField_5Label.Visible = 'on';
                    app.EditField_5.Visible = 'on';
        
                    app.EditField_6Label.Text = "$I1^\infty =$";
                    app.EditField_6.Value = double(round(E_I1));
                    app.EditField_6Label.Visible = 'on';
                    app.EditField_6.Visible = 'on';
        
                    app.EditField_7Label.Text = "$I2^\infty =$";
                    app.EditField_7.Value = double(round(E_I2));
                    app.EditField_7Label.Visible = 'on';
                    app.EditField_7.Visible = 'on';
        
                    app.EditField_8Label.Text = "$R1^\infty =$";
                    app.EditField_8.Value = double(round(E_R1));
                    app.EditField_8Label.Visible = 'on';
                    app.EditField_8.Visible = 'on';
        
                    app.EditField_9Label.Text = "$R2^\infty =$";
                    app.EditField_9.Value = double(round(E_R2));
                    app.EditField_9Label.Visible = 'on';
                    app.EditField_9.Visible = 'on';
        
                    app.EditField_10Label.Visible = 'off';
                    app.EditField_10.Visible = 'off';
        
                    app.EditField_11Label.Visible = 'off';
                    app.EditField_11.Visible = 'off';
        
        
                    box(app.UIAxes,'on')
                    hold(app.UIAxes,'on')
        
                   % legend(app.UIAxes,{'$S_1(t)$','$S_2(t)$','$L_1(t)$','$L_2(t)$','$I_1(t)$','$I_2(t)$','$R_1(t)$','$R_2(t)$'},...
                    %    'Location','northeast','NumColumns',2,'Interpreter','latex')
        
                end
        
                function modelA(app)
        
        
                    Time = app.EEditField_14.Value;
        
        
                    %zakladne parametre
                    beta = app.EEditField_7.Value; % základna infekčnosť
                    alpha = app.EEditField_8.Value;    %opustenie  I
                    eta = app.EEditField_9.Value;    %opustenie asymptomatických A
                    %eta = 0.5;    %opustenie asymptomatických A
                    k = app.EEditField_10.Value;       %rozdelenie medzi  A a I
                    %delta = 0.5;     %redukcia infekčnosti A  -> beta*delta*A
                    delta = app.EEditField_11.Value;     %redukcia infekčnosti A  -> beta*delta*A
                    p =app.EEditField_12.Value;          %miera rozdelenia L a A
                    f = app.EEditField_13.Value;        %miera umrtia  I
        
                    S_0 = app.EEditField.Value;
                    L_0 = app.EEditField_2.Value;
                    I_0 = app.EEditField_3.Value;
                    A_0 = app.EEditField_4.Value;
                    R_0 = app.EEditField_5.Value;
                    D_0 = app.EEditField_6.Value;
        
                    N = S_0+L_0+I_0+A_0+R_0+D_0;


                    fm = @(t,x) [
                        -x(1)*beta*(x(3)+delta*x(4));
                        x(1)*beta*(x(3)+delta*x(4))-k*x(2);
                        p*k*x(2)-alpha*x(3);
                        (1-p)*k*x(2)-eta*x(4);
                        f*alpha*x(3)+eta*x(4);
                        (1-f)*alpha*x(3)];
        
                    [t,xa] = ode45(fm,[0 Time],[S_0 L_0 I_0 A_0 R_0 D_0]);
        
                    Rep_0 = beta*N*((p/alpha)+(((1-p)*delta)/eta));
                    Rep_A = beta*N*(((1-p)*delta)/eta);
                    Rep_I = beta*N*((p/alpha));
        
        
                    box(app.UIAxes,'on')
                    hold(app.UIAxes,'on')
        
                    title(app.UIAxes,'Chrípkový model A','interpreter','latex')
                    plot(app.UIAxes,t,xa(:,1),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,3),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,4),'Color',[1     0.41176     0.16078],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,5),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,6),'Color',[ 0 0 0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie D(t)')
                    hold(app.UIAxes,'on')


                    E_S = xa(end,1);
                    E_L = xa(end,2);
                    E_I = xa(end,3);
                    E_A = xa(end,4);
                    E_R = xa(end,5);
                    E_D = xa(end,6);
        
                   % legend(app.UIAxes,{'$S(t)$','$L(t)$','$I(t)$','$A(t)$','$R(t)$','$D(t)$'},...
                    %    'Location','northeast','NumColumns',2,'Interpreter','latex')
        
        
                    app.EditField.Value = round(Rep_0,2);
                    app.EditFieldLabel.Text = "$\mathcal{R}_0$ =";
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField.Visible = 'on';
        
                    app.EditField_2Label.Text = "$S^\infty =$";
                    app.EditField_2.Value = double(round(E_S));
                    app.EditField_2Label.Visible = 'on';
                    app.EditField_2.Visible = 'on';
        
                    app.EditField_3Label.Text = "$L^\infty =$";
                    app.EditField_3.Value = double(round(E_L));
                    app.EditField_3Label.Visible = 'on';
                    app.EditField_3.Visible = 'on';
        
                    app.EditField_4Label.Text = "$I^\infty =$";
                    app.EditField_4.Value = double(round(E_I));
                    app.EditField_4Label.Visible = 'on';
                    app.EditField_4.Visible = 'on';
        
                    app.EditField_5Label.Text = "$A^\infty =$";
                    app.EditField_5.Value = double(round(E_A));
                    app.EditField_5Label.Visible = 'on';
                    app.EditField_5.Visible = 'on';
        
                    app.EditField_6Label.Text = "$R^\infty =$";
                    app.EditField_6.Value = double(round(E_R));
                    app.EditField_6Label.Visible = 'on';
                    app.EditField_6.Visible = 'on';
        
                    app.EditField_7Label.Text = "$D^\infty =$";
                    app.EditField_7.Value = double(round(E_D));
                    app.EditField_7Label.Visible = 'on';
                    app.EditField_7.Visible = 'on';
        
                    app.EditField_8Label.Visible = 'off';
                    app.EditField_8.Visible = 'off';
        
                    app.EditField_9Label.Visible = 'off';
                    app.EditField_9.Visible = 'off';
        
                    app.EditField_10Label.Visible = 'off';
                    app.EditField_10.Visible = 'off';
        
                    app.EditField_11Label.Visible = 'off';
                    app.EditField_11.Visible = 'off';
        
        
                    app.EditField_12Label.Text = "$\mathcal{R}_I =$";
                    app.EditField_12.Value = double(round(Rep_I,2));
                    app.EditField_12Label.Visible = 'on';
                    app.EditField_12.Visible = 'on';
        
        
                    app.EditField_13Label.Text = "$\mathcal{R}_A =$";
                    app.EditField_13.Value = double(round(Rep_A,2));
                    app.EditField_13Label.Visible = 'on';
                    app.EditField_13.Visible = 'on';


                    %generacia I
        
                    fi = @(t,x) [
                        -x(1).*beta.*(x(3));
                        x(1).*beta.*(x(3))-k.*x(2);
                        p.*k.*x(2)-alpha.*x(3);
                        f.*alpha.*x(3);
                        (1-f)*alpha.*x(3)];
        
                    [t,xi] = ode45(fi,[0 Time],[S_0 L_0 I_0 R_0 D_0]);
                    app.UIAxes_generation1
        
                    title(app.UIAxes_generation1,'Generácia infekčných',"$\mathcal{R}_I$",'interpreter','latex')
                    plot(app.UIAxes_generation1,t,xi(:,1),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xi(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xi(:,3),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xi(:,4),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xi(:,5),'Color',[ 0 0 0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie D(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
        
        
                    ylim(app.UIAxes_generation1,[0 inf])
                    xlim(app.UIAxes_generation1,[0 inf])
        
                    box(app.UIAxes_generation1,'on')
                    hold(app.UIAxes_generation1,'on')


                    %generacia A
        
                    fa = @(t,x) [
                        -x(1)*beta*(delta*x(3));
                        x(1)*beta*(delta*x(3))-k*x(2);
                        (1-p)*k*x(2)-eta*x(3);
                        eta*x(3);
                        ];
        
                    [t,xs] = ode45(fa,[0 Time],[S_0 L_0 A_0 R_0]);
        
                    title(app.UIAxes_generation2,'Generácia asymptomatických',"$\mathcal{R}_A$",'interpreter','latex')
        
                    plot(app.UIAxes_generation2,t,xs(:,1),'Color',[0.0745098039215686 0.623529411764706 1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xs(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xs(:,3),'Color',[1     0.41176     0.16078],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xs(:,4),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
        
                    ylim(app.UIAxes_generation2,[0 inf])
                    xlim(app.UIAxes_generation2,[0 inf])
        
                    box(app.UIAxes_generation2,'on')
                    hold(app.UIAxes_generation2,'on')


                end
        
                function modelB(app)
        
                    Time=app.EEditField_26.Value;
        
                    N = app.EEditField.Value;
                    %zakladne parametre
                    beta = app.EEditField_10.Value; % základna infekčnosť
                    alpha = app.EEditField_11.Value;    %opustenie  I
                    eta = app.EEditField_12.Value;    %opustenie asymptomatických A
                    %eta = 0.5;    %opustenie asymptomatických A
                    k = app.EEditField_13.Value;       %rozdelenie medzi  A a I
                    %delta = 0.5;     %redukcia infekčnosti A  -> beta*delta*A
                    delta = app.EEditField_14.Value;     %redukcia infekčnosti A  -> beta*delta*A
                    p =app.EEditField_15.Value;          %miera rozdelenia L a A
                    f = app.EEditField_16.Value;        %miera umrtia  I
        
                    L_0 = app.EEditField_2.Value;
                    I_0 = app.EEditField_4.Value;
                    A_0 = app.EEditField_6.Value;
                    R_0 = app.EEditField_8.Value;
                    D_0 = app.EEditField_9.Value;
        
                    %nove parametre
                    gamma = app.EEditField_17.Value;    %rozdelenie populacie (y*N   (1-y)*N)
                    %gamma = 0.5;    %rozdelenie populacie (y*N   (1-y)*N)
                    alpha_T = app.EEditField_18.Value;  %opustenie vakcinovanych I_T
                    eta_T = app.EEditField_19.Value;  %opustenie asymptomatických vakcinovanych A_T
                    k_T = app.EEditField_20.Value;       %rozdelenie medzi  A_T a I_T
                    tau = app.EEditField_21.Value;       %redukcia počtu vakcínovaných latenčných členov L_T
                    f_T = app.EEditField_22.Value;       %miera umrtia vakcinovaných I_T
        
                    sigma_S = app.EEditField_23.Value;   %redukcia náchylnosti vakcínovaných S_T
                    sigma_A = app.EEditField_24.Value;   %redukcia náchylnosti stretom s vakcínovaným A_T
                    sigma_I = app.EEditField_25.Value;   %redukcia náchylnosti stretom s vakcínovaným I_T
        
                    %sigma_S = 1;   %redukcia náchylnosti vakcínovaných S_T
                    %sigma_A = 1;   %redukcia náchylnosti stretom s vakcínovaným A_T
                    %sigma_I = 1;   %redukcia náchylnosti stretom s vakcínovaným I_T
        
                    LT_0 = app.EEditField_3.Value;
                    IT_0 = app.EEditField_5.Value;
                    AT_0 = app.EEditField_7.Value;


                    NS_0 = N - L_0 - LT_0 - I_0 - IT_0 -A_0 -AT_0 -R_0 - D_0;
        
                    S_0 = (1-gamma)*NS_0
                    ST_0 = gamma*NS_0


                    fm = @(t,x)[-x(1)*beta*x(5)-x(1)*beta*delta*x(7)-x(1)*beta*sigma_I*x(6)-x(1)*beta*delta*sigma_A*x(8);
                        -sigma_S*x(2)*beta*x(5)-sigma_S*x(2)*beta*delta*x(7)-sigma_S*x(2)*beta*sigma_I*x(6)-sigma_S*x(2)*beta*delta*sigma_A*x(8);
                        x(1)*beta*x(5)+x(1)*beta*delta*x(7)+x(1)*beta*sigma_I*x(6)+x(1)*beta*delta*sigma_A*x(8)-k*x(3);
                        sigma_S*x(2)*beta*x(5)+sigma_S*x(2)*beta*delta*x(7)+sigma_S*x(2)*beta*sigma_I*x(6)+sigma_S*x(2)*beta*delta*sigma_A*x(8)-k_T*x(4);
                        p*k*x(3)-alpha*x(5);
                        tau*p*k_T*x(4)-alpha_T*x(6);
                        (1-p)*k*x(3)-eta*x(7);
                        (1-p*tau)*k_T*x(4)-eta_T*x(8);
                        f*alpha*x(5)+f_T*alpha_T*x(6)+eta*x(7)+eta_T*x(8);
                        (1-f)*alpha*x(5)+(1-f_T)*alpha*x(6)];
                    [t,xa] = ode45(fm,[0 Time],[S_0 ST_0 L_0 LT_0 I_0 IT_0 A_0 AT_0 R_0 D_0]);
        
                    Rep_u = (1-gamma)*beta*N*((p/alpha)+((1-p)*delta)/eta);
                    Rep_v = gamma*sigma_S*beta*N*(  ((p*tau*sigma_I)/alpha_T)+     ((delta*(1-p*tau)*sigma_A)/eta_T)     );
                    Rep_0 = (1-gamma)*beta*N*((p/alpha)+((1-p)*delta)/eta) + gamma*sigma_S*beta*N*(  ((p*tau*sigma_I)/alpha_T)+     ((delta*(1-p*tau)*sigma_A)/eta_T)     );


                    title(app.UIAxes,'Chrípkový model B','interpreter','latex')


                    plot(app.UIAxes,t,xa(:,1),'Color',[0.07451     0.62353           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,2),'Color',[0.07451     0.62353           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S_T(t)',LineStyle='-.')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,4),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L_T(t)',LineStyle='-.')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,5),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,6),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I_T(t)',LineStyle='-.')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,7),'Color',[0.85098     0.32549    0.098039],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,8),'Color',[0.85098     0.32549    0.098039],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A_T(t)',LineStyle='-.')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,9),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,10),'Color',[0 0 0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie D(t)')
                    hold(app.UIAxes,'on')
                    box(app.UIAxes,'on')


                    %legend(app.UIAxes,{'$S(t)$','$S_T(t)$','$L(t)$','$L_T(t)$','$I(t)$','$I_T(t)$','$A(t)$','$A_T(t)$','$R(t)$','$D(t)$'},...
                     %   'Location','northeast','NumColumns',2,'Interpreter','latex')
                    hold(app.UIAxes,'on')


                    E_S = xa(end,1)-1;
                    E_ST = xa(end,2);
                    E_L = xa(end,3);
                    E_LT = xa(end,4);
                    E_I = xa(end,5);
                    E_IT=xa(end,6);
                    E_A = xa(end,7);
                    E_AT=xa(end,8);
                    E_R = xa(end,9);
                    E_D = xa(end,10);


                    app.EditField.Value = round(Rep_0,2);
                    app.EditFieldLabel.Text = "$\mathcal{R}_0$ =";
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField.Visible = 'on';
        
                    app.EditField_2Label.Text = "$S^\infty =$";
                    app.EditField_2.Value = double(round(E_S));
                    app.EditField_2Label.Visible = 'on';
                    app.EditField_2.Visible = 'on';
        
                    app.EditField_3Label.Text = "$ST^\infty =$";
                    app.EditField_3.Value = double(round(E_ST));
                    app.EditField_3Label.Visible = 'on';
                    app.EditField_3.Visible = 'on';
        
                    app.EditField_4Label.Text = "$L^\infty =$";
                    app.EditField_4.Value = double(round(E_L));
                    app.EditField_4Label.Visible = 'on';
                    app.EditField_4.Visible = 'on';
        
                    app.EditField_5Label.Text = "$LT^\infty =$";
                    app.EditField_5.Value = double(round(E_LT));
                    app.EditField_5Label.Visible = 'on';
                    app.EditField_5.Visible = 'on';
        
                    app.EditField_6Label.Text = "$I^\infty =$";
                    app.EditField_6.Value = double(round(E_I));
                    app.EditField_6Label.Visible = 'on';
                    app.EditField_6.Visible = 'on';
        
                    app.EditField_7Label.Text = "$IT^\infty =$";
                    app.EditField_7.Value = double(round(E_IT));
                    app.EditField_7Label.Visible = 'on';
                    app.EditField_7.Visible = 'on';
        
        
                    app.EditField_8Label.Text = "$A^\infty =$";
                    app.EditField_8.Value = double(round(E_A));
                    app.EditField_8Label.Visible = 'on';
                    app.EditField_8.Visible = 'on';
        
                    app.EditField_9Label.Text = "$AT^\infty =$";
                    app.EditField_9.Value = double(round(E_AT));
                    app.EditField_9Label.Visible = 'on';
                    app.EditField_9.Visible = 'on';
        
                    app.EditField_10Label.Text = "$R^\infty =$";
                    app.EditField_10.Value = double(round(E_R));
                    app.EditField_10Label.Visible = 'on';
                    app.EditField_10.Visible = 'on';
        
                    app.EditField_11Label.Text = "$D^\infty =$";
                    app.EditField_11.Value = double(round(E_D));
                    app.EditField_11Label.Visible = 'on';
                    app.EditField_11.Visible = 'on';
        
        
                    app.EditField_12Label.Text = "$\mathcal{R}_N =$";
                    app.EditField_12.Value = double(round(Rep_u,2));
                    app.EditField_12Label.Visible = 'on';
                    app.EditField_12.Visible = 'on';
        
        
                    app.EditField_13Label.Text = "$\mathcal{R}_V =$";
                    app.EditField_13.Value = double(round(Rep_v,2));
                    app.EditField_13Label.Visible = 'on';
                    app.EditField_13.Visible = 'on';


                    fN = @(t,x)[
                        -x(1)*beta*x(3)-x(1)*beta*delta*x(4);
                        x(1)*beta*x(3)+x(1)*beta*delta*x(4)-k*x(2);
                        p*k*x(2)-alpha*x(3);
                        (1-p)*k*x(2)-eta*x(4);
                        f*alpha*x(3)+eta*x(4);
                        (1-f)*alpha*x(3)];
                    [t,xN] = ode45(fN,[0 Time],[S_0  L_0  I_0  A_0  R_0 D_0]);
        
        
                    title(app.UIAxes_generation1,'Generácia nevakcinovaných',"$\mathcal{R}_N$",'interpreter','latex')
        
        
                    plot(app.UIAxes_generation1,t,xN(:,1),'Color',[0.07451     0.62353           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xN(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xN(:,3),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xN(:,4),'Color',[0.85098     0.32549    0.098039],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
        
                    plot(app.UIAxes_generation1,t,xN(:,5),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle',':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xN(:,6),'Color',[0 0 0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie D(t)','LineStyle',':')
        
                    ylim(app.UIAxes_generation1,[0 inf])
                    xlim(app.UIAxes_generation1,[0 inf])
        
                    box(app.UIAxes_generation1,'on')
                    hold(app.UIAxes_generation1,'on')
        
        
                    fV = @(t,x)[
                        -sigma_S*x(1)*beta*sigma_I*x(3)-sigma_S*x(1)*beta*delta*sigma_A*x(4);
                        sigma_S*x(1)*beta*sigma_I*x(3)+sigma_S*x(1)*beta*delta*sigma_A*x(4)-k_T*x(2);
                        tau*p*k_T*x(2)-alpha_T*x(3);
                        (1-p*tau)*k_T*x(2)-eta_T*x(4);
                        f_T*alpha_T*x(3)+eta_T*x(4);
                        (1-f_T)*alpha*x(3)];
                    [t,xV] = ode45(fV,[0 Time],[ ST_0 LT_0 IT_0 AT_0 R_0 D_0]);
        
                    title(app.UIAxes_generation2,'Generácia vakcinovaných',"$\mathcal{R}_V$",'interpreter','latex')
        
                    plot(app.UIAxes_generation2,t,xV(:,1),'Color',[0.07451     0.62353           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S_T(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
        
                    plot(app.UIAxes_generation2,t,xV(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L_T(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xV(:,3),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I_T(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
        
                    plot(app.UIAxes_generation2,t,xV(:,4),'Color',[0.85098     0.32549    0.098039],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A_T(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xV(:,5),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xV(:,6),'Color',[0 0 0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie D(t)','LineStyle','--')
                    hold(app.UIAxes_generation2,'on')
        
                    ylim(app.UIAxes_generation2,[0 inf])
                    xlim(app.UIAxes_generation2,[0 inf])
        
                    box(app.UIAxes_generation2,'on')
                    hold(app.UIAxes_generation2,'on')
        
        
                end
        
                function modelC(app)
        
        
                    N = app.EEditField.Value;
                    Time = app.EEditField_30.Value;
        
                    %parametre prvého modela
                    beta = app.EEditField_10.Value; % základna infekčnosť
                    alpha = app.EEditField_11.Value;    %opustenie  I
                    eta = app.EEditField_12.Value;    %opustenie asymptomatických A
                    k = app.EEditField_13.Value;       %rozdelenie medzi  A a I
                    delta = app.EEditField_14.Value;     %redukcia infekčnosti A  -> beta*delta*A
                    p =app.EEditField_15.Value;          %miera rozdelenia L a A
                    f = app.EEditField_16.Value;        %miera umrtia  I
        
        
                    L_0 = app.EEditField_2.Value;
                    I_0 = app.EEditField_4.Value;
                    A_0 = app.EEditField_6.Value;
                    R_0 = app.EEditField_8.Value;
                    D_0 = app.EEditField_9.Value;
        
                    %parametre z druheho chrípkoveho modela
                    %tuna nepotrebne  gamma = 0.5;    %rozdelenie populacie (y*N   (1-y)*N)
                    alpha_T = app.EEditField_17.Value;  %opustenie vakcinovanych I_T
                    eta_T = app.EEditField_18.Value;  %opustenie asymptomatických vakcinovanych A_T
                    k_T = app.EEditField_19.Value;       %rozdelenie medzi  A_T a I_T
                    tau = app.EEditField_20.Value;       %redukcia počtu vakcínovaných - liečenych latenčných členov L_T
                    f_T = app.EEditField_21.Value;       %miera umrtia vakcinovaných I_T


                    %tuna nepotrebne nemame vakcinovaných náchylnych  sigma_S = 0.6;   %redukcia náchylnosti vakcínovaných S_T
                    sigma_A = app.EEditField_22.Value;   %redukcia náchylnosti stretom s liečeným A_T
                    sigma_I = app.EEditField_23.Value;   %redukcia náchylnosti stretom s liečeným I_T
        
                    LT_0 = app.EEditField_3.Value;
                    IT_0 = app.EEditField_5.Value;
                    AT_0 = app.EEditField_7.Value;
        
        
                    theta_L = app.EEditField_24.Value;
                    theta_I = app.EEditField_25.Value;
                    theta_A = app.EEditField_26.Value;
                    varphi_I = app.EEditField_28.Value;
                    varphi_L = app.EEditField_27.Value;
                    varphi_A = app.EEditField_29.Value;
        
                    S_0 = N - (L_0+I_0+A_0+R_0+D_0+IT_0+AT_0+LT_0);
        
        
                    fm = @(t,x)[
                        -x(1)*beta*x(4)-x(1)*beta*delta*x(6)-x(1)*beta*sigma_I*x(5)-x(1)*beta*delta*sigma_A*x(7);
                        x(1)*beta*x(4)+x(1)*beta*delta*x(6)+x(1)*beta*sigma_I*x(5)+x(1)*beta*delta*sigma_A*x(7)-k*x(2)-varphi_L*x(2)+theta_L*x(3);
                        -k_T*x(3)+varphi_L*x(2)-theta_L*x(3);
                        p*k*x(2)-alpha*x(4)-varphi_I*x(4)+theta_I*x(5);
                        tau*p*k_T*x(3)-alpha_T*x(5)+varphi_I*x(4)-theta_I*x(5);
                        (1-p)*k*x(2)-eta*x(6)-varphi_A*x(6)+theta_A*x(7);
                        (1-p*tau)*k_T*x(3)-eta_T*x(7)+varphi_A*x(6)-theta_A*x(7);
                        f*alpha*x(4)+f_T*alpha_T*x(5)+eta*x(6)+eta_T*x(7);
                        (1-f)*alpha*x(4)+(1-f_T)*alpha_T*x(5)];
                    [t,xa] = ode45(fm,[0 Time],[S_0 L_0 LT_0 I_0 IT_0 A_0 AT_0 R_0 D_0]);


                    title(app.UIAxes,'Chrípkový model C','interpreter','latex')
        
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,1),'Color',[0.07451     0.62353           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L_T(t)',LineStyle='-.')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,4),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,5),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I_T(t)',LineStyle='-.')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,6),'Color',[0.85098     0.32549    0.098039],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,7),'Color',[0.85098     0.32549    0.098039],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A_T(t)',LineStyle='-.')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,8),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,xa(:,9),'Color',[0 0 0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie D(t)')
                    hold(app.UIAxes,'on')


                   % legend(app.UIAxes,{'$S(t)$','$L(t)$','$L_T(t)$','$I(t)$','$I_T(t)$','$A(t)$','$A_T(t)$','$R(t)$','$D(t)$'},...
                       % 'Location','northeast','NumColumns',2,'Interpreter','latex')
                    hold(app.UIAxes,'on')
                    Delta_I=alpha*(alpha_T+theta_I)+varphi_I*alpha_T;
                    Delta_L=k*(k_T+theta_L)+varphi_L*k_T;
                    p_I=sigma_I*(k*k_T*varphi_I+theta_L*k*varphi_I+alpha*k_T*varphi_L*tau+k_T*varphi_I*varphi_L*tau);
                    p_L=alpha_T*k*k_T+theta_I*theta_L*k+theta_L*alpha_T*k+theta_I*k*k_T+theta_I*k_T*varphi_L*tau;


                    Rep_I = ((N*beta*p)/(Delta_L*Delta_I))*(p_I+p_L);
        
                    REP_A = (N*beta*delta*((1-p)*k*(theta_L + k_T)*(theta_A + eta_T + varphi_A*sigma_A) + varphi_L*(1-p*tau)*k_T*(theta_A + varphi_A*sigma_A + eta*sigma_A)))/((theta_L*k + varphi_L*k_T + k*k_T)*(theta_A*eta + varphi_A*eta_T + eta*eta_T));
                    REP_I = (N*beta*p*(k*(theta_L + k_T)*(theta_I + alpha_T + varphi_I*sigma_I) + varphi_L*k_T*tau*(theta_I + varphi_I*sigma_I + alpha*sigma_I)))/((theta_I*alpha + varphi_I*alpha_T + alpha*alpha_T)*(theta_L*k + varphi_L*k_T + k*k_T));
                    Rep_0 = REP_A+REP_I;


                    E_S = xa(end,1);
                    E_L = xa(end,2);
                    E_LT = xa(end,3);
                    E_I = xa(end,4);
                    E_IT=xa(end,5);
                    E_A = xa(end,6);
                    E_AT=xa(end,7);
                    E_R = xa(end,8);
                    E_D = xa(end,9);


                    app.EditField.Value = round(Rep_0,2);
                    app.EditFieldLabel.Text = "$\mathcal{R}_0$ =";
                    app.EditFieldLabel.Visible = 'on';
                    app.EditField.Visible = 'on';
        
                    app.EditField_2Label.Text = "$S^\infty =$";
                    app.EditField_2.Value = double(round(E_S));
                    app.EditField_2Label.Visible = 'on';
                    app.EditField_2.Visible = 'on';
        
                    app.EditField_3Label.Text = "$L^\infty =$";
                    app.EditField_3.Value = double(round(E_L));
                    app.EditField_3Label.Visible = 'on';
                    app.EditField_3.Visible = 'on';
        
                    app.EditField_4Label.Text = "$LT^\infty =$";
                    app.EditField_4.Value = double(round(E_LT));
                    app.EditField_4Label.Visible = 'on';
                    app.EditField_4.Visible = 'on';
        
                    app.EditField_5Label.Text = "$I^\infty =$";
                    app.EditField_5.Value = double(round(E_I));
                    app.EditField_5Label.Visible = 'on';
                    app.EditField_5.Visible = 'on';
        
                    app.EditField_6Label.Text = "$IT^\infty =$";
                    app.EditField_6.Value = double(round(E_IT));
                    app.EditField_6Label.Visible = 'on';
                    app.EditField_6.Visible = 'on';
        
                    app.EditField_7Label.Text = "$A^\infty =$";
                    app.EditField_7.Value = double(round(E_A));
                    app.EditField_7Label.Visible = 'on';
                    app.EditField_7.Visible = 'on';
        
        
                    app.EditField_8Label.Text = "$AT^\infty =$";
                    app.EditField_8.Value = double(round(E_AT));
                    app.EditField_8Label.Visible = 'on';
                    app.EditField_8.Visible = 'on';
        
                    app.EditField_9Label.Text = "$R^\infty =$";
                    app.EditField_9.Value = double(round(E_R));
                    app.EditField_9Label.Visible = 'on';
                    app.EditField_9.Visible = 'on';
        
                    app.EditField_10Label.Text = "$D^\infty =$";
                    app.EditField_10.Value = double(round(E_D));
                    app.EditField_10Label.Visible = 'on';
                    app.EditField_10.Visible = 'on';
        
        
                    app.EditField_11Label.Visible = 'off';
                    app.EditField_11.Visible = 'off';
        
        
                    app.EditField_12Label.Text = "$\mathcal{R}_I =$";
                    app.EditField_12.Value = double(round(Rep_I,2));
                    app.EditField_12Label.Visible = 'on';
                    app.EditField_12.Visible = 'on';
        
        
                    app.EditField_13Label.Text = "$\mathcal{R}_A =$";
                    app.EditField_13.Value = double(round(REP_A,2));
                    app.EditField_13Label.Visible = 'on';
                    app.EditField_13.Visible = 'on';


                    fI = @(t,x)[
                        -x(1)*beta*x(4)-x(1)*beta*sigma_I*x(5);
                        x(1)*beta*x(4)+x(1)*beta*sigma_I*x(5)-k*x(2)-varphi_L*x(2)+theta_L*x(3);
                        -k_T*x(3)+varphi_L*x(2)-theta_L*x(3);
                        p*k*x(2)-alpha*x(4)-varphi_I*x(4)+theta_I*x(5);
                        tau*p*k_T*x(3)-alpha_T*x(5)+varphi_I*x(4)-theta_I*x(5);
                        f*alpha*x(4)+f_T*alpha_T*x(5);
                        (1-f)*alpha*x(4)+(1-f_T)*alpha_T*x(5)];
                    [t,xI] = ode45(fI,[0 Time],[S_0 L_0 LT_0 I_0 IT_0 R_0 D_0]);
        
                    title(app.UIAxes_generation1,'Generácia infekčných',"$\mathcal{R}_I$",'interpreter','latex')
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes_generation1,t,xI(:,1),'Color',[0.07451     0.62353           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)',LineStyle=':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xI(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)',LineStyle=':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xI(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L_T(t)',LineStyle=':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xI(:,4),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I(t)',LineStyle=':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xI(:,5),'Color',[1  0  0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie I_T(t)',LineStyle=':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xI(:,6),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)',LineStyle=':')
                    hold(app.UIAxes_generation1,'on')
                    plot(app.UIAxes_generation1,t,xI(:,7),'Color',[0 0 0],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie D(t)',LineStyle=':')
                    hold(app.UIAxes_generation1,'on')
        
                    ylim(app.UIAxes_generation1,[0 inf])
                    hold(app.UIAxes_generation1,'on')
                    xlim(app.UIAxes_generation1,[0 inf])
                    hold(app.UIAxes_generation1,'on')
                    box(app.UIAxes_generation1,'on')
                    hold(app.UIAxes_generation1,'on')


                    fAS = @(t,x)[
                        -x(1)*beta*delta*x(4)-x(1)*beta*delta*sigma_A*x(5);
                        x(1)*beta*delta*x(4)+x(1)*beta*delta*sigma_A*x(5)-k*x(2)-varphi_L*x(2)+theta_L*x(3);
                        -k_T*x(3)+varphi_L*x(2)-theta_L*x(3);
                        (1-p)*k*x(2)-eta*x(4)-varphi_A*x(4)+theta_A*x(5);
                        (1-p*tau)*k_T*x(3)-eta_T*x(5)+varphi_A*x(4)-theta_A*x(5);
                        eta*x(4)+eta_T*x(5);
                        ];
        
                    [t,xAS] = ode45(fAS,[0 Time],[S_0 L_0 LT_0 A_0 AT_0 R_0]);
        
                    title(app.UIAxes_generation2,'Generácia asymptomatických',"$\mathcal{R}_A$",'interpreter','latex')
                    hold(app.UIAxes,'on')
        
                    plot(app.UIAxes_generation2,t,xAS(:,1),'Color',[0.07451     0.62353           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie S(t)',LineStyle='--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xAS(:,2),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L(t)',LineStyle='--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xAS(:,3),'Color',[1           1    0.066667],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie L_T(t)',LineStyle='--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xAS(:,4),'Color',[0.85098     0.32549    0.098039],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A(t)',LineStyle='--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xAS(:,5),'Color',[0.85098     0.32549    0.098039],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie A_T(t)',LineStyle='--')
                    hold(app.UIAxes_generation2,'on')
                    plot(app.UIAxes_generation2,t,xAS(:,6),'Color',[0.71765     0.27451           1],...
                        'LineWidth',2,'DisplayName','Priebeh funkcie R(t)',LineStyle='--')
                    hold(app.UIAxes_generation2,'on')
        
                    ylim(app.UIAxes_generation2,[0 inf])
                    hold(app.UIAxes_generation2,'on')
                    xlim(app.UIAxes_generation2,[0 inf])
                    hold(app.UIAxes_generation2,'on')
                    box(app.UIAxes_generation2,'on')
                    hold(app.UIAxes_generation2,'on')


                end
            end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PrepotaButton
        function Callbacks_PrepotaButton(app, event)
        
                    dropDownvalue = app.DropDownSelectModel.Value;
                    if dropDownvalue == "Model 1"
                        cla(app.UIAxes)
                        cla(app.UIAxes_generation1)
                        cla(app.UIAxes_generation2)
                        model1(app);
        
                        imageFile = 'resources/images/legend/legend1.svg';
                        app.Image2.ImageSource = imageFile;
        
                    end
        
                    if dropDownvalue == "Model 3"
                        cla(app.UIAxes)
                        cla(app.UIAxes_generation1)
                        cla(app.UIAxes_generation2)
                        model3(app);

                         imageFile = 'resources/images/legend/legend2.svg';
                        app.Image2.ImageSource = imageFile;
                    end
        
                    if dropDownvalue == "Model 2"
                        cla(app.UIAxes)
                        cla(app.UIAxes_generation1)
                        cla(app.UIAxes_generation2)
                        model2(app);

                         imageFile = 'resources/images/legend/legend3.svg';
                        app.Image2.ImageSource = imageFile;
                    end
        
                    if dropDownvalue == "Model A"
                        cla(app.UIAxes)
                        cla(app.UIAxes_generation1)
                        cla(app.UIAxes_generation2)
                        modelA(app);

                         imageFile = 'resources/images/legend/legendA.svg';
                        app.Image2.ImageSource = imageFile;
                    end
        
                    if dropDownvalue == "Model B"
                        cla(app.UIAxes)
                        cla(app.UIAxes_generation1)
                        cla(app.UIAxes_generation2)
                        modelB(app);

                         imageFile = 'resources/images/legend/legendB.svg';
                        app.Image2.ImageSource = imageFile;
                    end
        
                    if dropDownvalue == "Model C"
                        cla(app.UIAxes)
                        cla(app.UIAxes_generation1)
                        cla(app.UIAxes_generation2)
                        modelC(app);

                         imageFile = 'resources/images/legend/legendC.svg';
                        app.Image2.ImageSource = imageFile;
                    end
        end

        % Value changed function: DropDownSelectModel
        function DropDownSelectModelValueChanged(app, event)
                    value = app.DropDownSelectModel.Value;
        
        
                    if value == "Model C"
        
                        imageFile = 'resources/images/schema/modelC.svg';
                        app.Image.ImageSource = imageFile;
        
                        app.EEditField.Visible = 'on';
                        app.EEditFieldLabel.Visible = 'on';
                        app.EEditFieldLabel.Text = "$N$";
                        app.EEditField.Value = double(1000);
        
                        app.EEditField_2.Visible = 'on';
                        app.EEditField_2Label.Visible = 'on';
                        app.EEditField_2Label.Text = "$L_0$";
                        app.EEditField_2.Value = double(0);
        
                        app.EEditField_3.Visible = 'on';
                        app.EEditField_3Label.Visible = 'on';
                        app.EEditField_3Label.Text = "$LT_0$";
                        app.EEditField_3.Value = double(0);
        
                        app.EEditField_4.Visible = 'on';
                        app.EEditField_4Label.Visible = 'on';
                        app.EEditField_4Label.Text = "$I_0$";
                        app.EEditField_4.Value = double(10);
        
                        app.EEditField_5.Visible = 'on';
                        app.EEditField_5Label.Visible = 'on';
                        app.EEditField_5Label.Text = "$IT_0$";
                        app.EEditField_5.Value = double(0);
        
                        app.EEditField_6.Visible = 'on';
                        app.EEditField_6Label.Visible = 'on';
                        app.EEditField_6Label.Text = "$A_0$";
                        app.EEditField_6.Value = double(10);
        
                        app.EEditField_7.Visible = 'on';
                        app.EEditField_7Label.Visible = 'on';
                        app.EEditField_7Label.Text = "$AT_0$";
                        app.EEditField_7.Value = double(0);
        
                        app.EEditField_8.Visible = 'on';
                        app.EEditField_8Label.Visible = 'on';
                        app.EEditField_8Label.Text = "$R_0$";
                        app.EEditField_8.Value = double(0);
        
                        app.EEditField_9.Visible = 'on';
                        app.EEditField_9Label.Visible = 'on';
                        app.EEditField_9Label.Text = "$D_0$";
                        app.EEditField_9.Value = double(0);
        
                        app.EEditField_10.Visible = 'on';
                        app.EEditField_10Label.Visible = 'on';
                        app.EEditField_10Label.Text = "$\beta$";
                        app.EEditField_10.Value = double(0.0009);
        
                        app.EEditField_11.Visible = 'on';
                        app.EEditField_11Label.Visible = 'on';
                        app.EEditField_11Label.Text = "$\alpha$";
                        app.EEditField_11.Value = double(0.25);
        
                        app.EEditField_12.Visible = 'on';
                        app.EEditField_12Label.Visible = 'on';
                        app.EEditField_12Label.Text = "$\eta$";
                        app.EEditField_12.Value = double(0.3);
        
                        app.EEditField_13.Visible = 'on';
                        app.EEditField_13Label.Visible = 'on';
                        app.EEditField_13Label.Text = "$k$";
                        app.EEditField_13.Value = double(0.5);
        
                        app.EEditField_14.Visible = 'on';
                        app.EEditField_14Label.Visible = 'on';
                        app.EEditField_14Label.Text = "$\delta$";
                        app.EEditField_14.Value = double(0.9);
        
                        app.EEditField_15.Visible = 'on';
                        app.EEditField_15Label.Visible = 'on';
                        app.EEditField_15Label.Text = "$p$";
                        app.EEditField_15.Value = double(0.5);
        
                        app.EEditField_16.Visible = 'on';
                        app.EEditField_16Label.Visible = 'on';
                        app.EEditField_16Label.Text = "$f$";
                        app.EEditField_16.Value = double(0.98);
        
                        app.EEditField_17.Visible = 'on';
                        app.EEditField_17Label.Visible = 'on';
                        app.EEditField_17Label.Text = "$\alpha_T$";
                        app.EEditField_17.Value = double(0.3);
        
                        app.EEditField_18.Visible = 'on';
                        app.EEditField_18Label.Visible = 'on';
                        app.EEditField_18Label.Text = "$\eta_T$";
                        app.EEditField_18.Value = double(0.4);
        
                        app.EEditField_19.Visible = 'on';
                        app.EEditField_19Label.Visible = 'on';
                        app.EEditField_19Label.Text = "$k_T$";
                        app.EEditField_19.Value = double(0.5);
        
                        app.EEditField_20.Visible = 'on';
                        app.EEditField_20Label.Visible = 'on';
                        app.EEditField_20Label.Text = "$\tau$";
                        app.EEditField_20.Value = double(0.8);
        
                        app.EEditField_21.Visible = 'on';
                        app.EEditField_21Label.Visible = 'on';
                        app.EEditField_21Label.Text = "$f_T$";
                        app.EEditField_21.Value = double(0.98);
        
                        app.EEditField_22.Visible = 'on';
                        app.EEditField_22Label.Visible = 'on';
                        app.EEditField_22Label.Text = "$\sigma_A$";
                        app.EEditField_22.Value = double(0.6);
        
                        app.EEditField_23.Visible = 'on';
                        app.EEditField_23Label.Visible = 'on';
                        app.EEditField_23Label.Text = "$\sigma_I$";
                        app.EEditField_23.Value = double(0.6);
        
                        app.EEditField_24.Visible = 'on';
                        app.EEditField_24Label.Visible = 'on';
                        app.EEditField_24Label.Text = "$\theta_L$";
                        app.EEditField_24.Value = double(0.1);
        
                        app.EEditField_25.Visible = 'on';
                        app.EEditField_25Label.Visible = 'on';
                        app.EEditField_25Label.Text = "$\theta_I$";
                        app.EEditField_25.Value = double(0.1);
        
                        app.EEditField_26.Visible = 'on';
                        app.EEditField_26Label.Visible = 'on';
                        app.EEditField_26Label.Text = "$\theta_A$";
                        app.EEditField_26.Value = double(0.1);
        
                        app.EEditField_27.Visible = 'on';
                        app.EEditField_27Label.Visible = 'on';
                        app.EEditField_27Label.Text = "$\varphi_L$";
                        app.EEditField_27.Value = double(0.51);
        
                        app.EEditField_28.Visible = 'on';
                        app.EEditField_28Label.Visible = 'on';
                        app.EEditField_28Label.Text = "$\varphi_I$";
                        app.EEditField_28.Value = double(1.22);
        
                        app.EEditField_29.Visible = 'on';
                        app.EEditField_29Label.Visible = 'on';
                        app.EEditField_29Label.Text = "$\varphi_A$";
                        app.EEditField_29.Value = double(0.22);
        
                        app.EEditField_30.Visible = 'on';
                        app.EEditField_30Label.Visible = 'on';
                        app.EEditField_30Label.Text = "$čas$";
                        app.EEditField_30.Value = double(200);
                    end
                    if value == "Model B"
        
                        imageFile = 'resources/images/schema/modelB.svg';
                        app.Image.ImageSource = imageFile;
        
                        app.EEditField_27.Visible = 'off';
                        app.EEditField_27Label.Visible = 'off';
                        app.EEditField_28.Visible = 'off';
                        app.EEditField_28Label.Visible = 'off';
                        app.EEditField_29.Visible = 'off';
                        app.EEditField_29Label.Visible = 'off';
                        app.EEditField_30.Visible = 'off';
                        app.EEditField_30Label.Visible = 'off';
                        app.EEditField.Visible = 'on';
                        app.EEditFieldLabel.Visible = 'on';
                        app.EEditFieldLabel.Text = "$N$";
                        app.EEditField.Value = double(1000);
        
                        app.EEditField_2.Visible = 'on';
                        app.EEditField_2Label.Visible = 'on';
                        app.EEditField_2Label.Text = "$L_0$";
                        app.EEditField_2.Value = double(0);
        
                        app.EEditField_3.Visible = 'on';
                        app.EEditField_3Label.Visible = 'on';
                        app.EEditField_3Label.Text = "$LT_0$";
                        app.EEditField_3.Value = double(0);
        
                        app.EEditField_4.Visible = 'on';
                        app.EEditField_4Label.Visible = 'on';
                        app.EEditField_4Label.Text = "$I_0$";
                        app.EEditField_4.Value = double(5);
        
                        app.EEditField_5.Visible = 'on';
                        app.EEditField_5Label.Visible = 'on';
                        app.EEditField_5Label.Text = "$IT_0$";
                        app.EEditField_5.Value = double(5);
        
                        app.EEditField_6.Visible = 'on';
                        app.EEditField_6Label.Visible = 'on';
                        app.EEditField_6Label.Text = "$A_0$";
                        app.EEditField_6.Value = double(5);
        
                        app.EEditField_7.Visible = 'on';
                        app.EEditField_7Label.Visible = 'on';
                        app.EEditField_7Label.Text = "$AT_0$";
                        app.EEditField_7.Value = double(5);
        
                        app.EEditField_8.Visible = 'on';
                        app.EEditField_8Label.Visible = 'on';
                        app.EEditField_8Label.Text = "$R_0$";
                        app.EEditField_8.Value = double(0);
        
                        app.EEditField_9.Visible = 'on';
                        app.EEditField_9Label.Visible = 'on';
                        app.EEditField_9Label.Text = "$D_0$";
                        app.EEditField_9.Value = double(0);
        
                        app.EEditField_10.Visible = 'on';
                        app.EEditField_10Label.Visible = 'on';
                        app.EEditField_10Label.Text = "$\beta$";
                        app.EEditField_10.Value = double(0.0009);
        
                        app.EEditField_11.Visible = 'on';
                        app.EEditField_11Label.Visible = 'on';
                        app.EEditField_11Label.Text = "$\alpha$";
                        app.EEditField_11.Value = double(0.25);
        
                        app.EEditField_12.Visible = 'on';
                        app.EEditField_12Label.Visible = 'on';
                        app.EEditField_12Label.Text = "$\eta$";
                        app.EEditField_12.Value = double(0.3);
        
                        app.EEditField_13.Visible = 'on';
                        app.EEditField_13Label.Visible = 'on';
                        app.EEditField_13Label.Text = "$k$";
                        app.EEditField_13.Value = double(0.5);
        
                        app.EEditField_14.Visible = 'on';
                        app.EEditField_14Label.Visible = 'on';
                        app.EEditField_14Label.Text = "$\delta$";
                        app.EEditField_14.Value = double(0.9);
        
                        app.EEditField_15.Visible = 'on';
                        app.EEditField_15Label.Visible = 'on';
                        app.EEditField_15Label.Text = "$p$";
                        app.EEditField_15.Value = double(0.5);
        
                        app.EEditField_16.Visible = 'on';
                        app.EEditField_16Label.Visible = 'on';
                        app.EEditField_16Label.Text = "$f$";
                        app.EEditField_16.Value = double(0.98);
        
        
                        app.EEditField_17.Visible = 'on';
                        app.EEditField_17Label.Visible = 'on';
                        app.EEditField_17Label.Text = "$\gamma$";
                        app.EEditField_17.Value = double(0.80);
        
        
                        app.EEditField_18.Visible = 'on';
                        app.EEditField_18Label.Visible = 'on';
                        app.EEditField_18Label.Text = "$\alpha_T$";
                        app.EEditField_18.Value = double(0.3);
        
        
                        app.EEditField_19.Visible = 'on';
                        app.EEditField_19Label.Visible = 'on';
                        app.EEditField_19Label.Text = "$\eta_T$";
                        app.EEditField_19.Value = double(0.4);
        
        
                        app.EEditField_20.Visible = 'on';
                        app.EEditField_20Label.Visible = 'on';
                        app.EEditField_20Label.Text = "$k_T$";
                        app.EEditField_20.Value = double(0.5);
        
        
                        app.EEditField_21.Visible = 'on';
                        app.EEditField_21Label.Visible = 'on';
                        app.EEditField_21Label.Text = "$\tau$";
                        app.EEditField_21.Value = double(0.8);
        
        
                        app.EEditField_22.Visible = 'on';
                        app.EEditField_22Label.Visible = 'on';
                        app.EEditField_22Label.Text = "$f_T$";
                        app.EEditField_22.Value = double(1);
        
        
                        app.EEditField_23.Visible = 'on';
                        app.EEditField_23Label.Visible = 'on';
                        app.EEditField_23Label.Text = "$\sigma_S$";
                        app.EEditField_23.Value = double(0.3);
        
        
                        app.EEditField_24.Visible = 'on';
                        app.EEditField_24Label.Visible = 'on';
                        app.EEditField_24Label.Text = "$\sigma_A$";
                        app.EEditField_24.Value = double(0.6);
        
        
                        app.EEditField_25.Visible = 'on';
                        app.EEditField_25Label.Visible = 'on';
                        app.EEditField_25Label.Text = "$\sigma_I$";
                        app.EEditField_25.Value = double(0.6);
        
                        app.EEditField_26.Visible = 'on';
                        app.EEditField_26Label.Visible = 'on';
                        app.EEditField_26Label.Text = "$čas$";
                        app.EEditField_26.Value = double(300);
        
        
                    end
                    if value == "Model A"
        
                        imageFile = 'resources/images/schema/modelA.svg';
                        app.Image.ImageSource = imageFile;
        
                        app.EEditField_15.Visible = 'off';
                        app.EEditField_15Label.Visible = 'off';
                        app.EEditField_16.Visible = 'off';
                        app.EEditField_16Label.Visible = 'off';
                        app.EEditField_17.Visible = 'off';
                        app.EEditField_17Label.Visible = 'off';
                        app.EEditField_18.Visible = 'off';
                        app.EEditField_18Label.Visible = 'off';
                        app.EEditField_19.Visible = 'off';
                        app.EEditField_19Label.Visible = 'off';
                        app.EEditField_20.Visible = 'off';
                        app.EEditField_20Label.Visible = 'off';
                        app.EEditField_21.Visible = 'off';
                        app.EEditField_21Label.Visible = 'off';
                        app.EEditField_22.Visible = 'off';
                        app.EEditField_22Label.Visible = 'off';
                        app.EEditField_23.Visible = 'off';
                        app.EEditField_23Label.Visible = 'off';
                        app.EEditField_24.Visible = 'off';
                        app.EEditField_24Label.Visible = 'off';
                        app.EEditField_25.Visible = 'off';
                        app.EEditField_25Label.Visible = 'off';
                        app.EEditField_26.Visible = 'off';
                        app.EEditField_26Label.Visible = 'off';
                        app.EEditField_27.Visible = 'off';
                        app.EEditField_27Label.Visible = 'off';
                        app.EEditField_28.Visible = 'off';
                        app.EEditField_28Label.Visible = 'off';
                        app.EEditField_29.Visible = 'off';
                        app.EEditField_29Label.Visible = 'off';
                        app.EEditField_30.Visible = 'off';
                        app.EEditField_30Label.Visible = 'off';

        
                        app.EEditField.Visible = 'on';
                        app.EEditFieldLabel.Visible = 'on';
                        app.EEditFieldLabel.Text = "$S_0$";
                        app.EEditField.Value = double(980);
        
                        app.EEditField_2.Visible = 'on';
                        app.EEditField_2Label.Visible = 'on';
                        app.EEditField_2Label.Text = "$L_0$";
                        app.EEditField_2.Value = double(0);
        
                        app.EEditField_3.Visible = 'on';
                        app.EEditField_3Label.Visible = 'on';
                        app.EEditField_3Label.Text = "$I_0$";
                        app.EEditField_3.Value = double(10);
        
                        app.EEditField_4.Visible = 'on';
                        app.EEditField_4Label.Visible = 'on';
                        app.EEditField_4Label.Text = "$A_0$";
                        app.EEditField_4.Value = double(10);
        
                        app.EEditField_5.Visible = 'on';
                        app.EEditField_5Label.Visible = 'on';
                        app.EEditField_5Label.Text = "$R_0$";
                        app.EEditField_5.Value = double(0);
        
                        app.EEditField_6.Visible = 'on';
                        app.EEditField_6Label.Visible = 'on';
                        app.EEditField_6Label.Text = "$D_0$";
                        app.EEditField_6.Value = double(0);
        
                        app.EEditField_7.Visible = 'on';
                        app.EEditField_7Label.Visible = 'on';
                        app.EEditField_7Label.Text = "$\beta$";
                        app.EEditField_7.Value = double(0.0006);
        
                        app.EEditField_8.Visible = 'on';
                        app.EEditField_8Label.Visible = 'on';
                        app.EEditField_8Label.Text = "$\alpha$";
                        app.EEditField_8.Value = double(0.25);
        
                        app.EEditField_9.Visible = 'on';
                        app.EEditField_9Label.Visible = 'on';
                        app.EEditField_9Label.Text = "$\eta$";
                        app.EEditField_9.Value = double(0.3);
        
                        app.EEditField_10.Visible = 'on';
                        app.EEditField_10Label.Visible = 'on';
                        app.EEditField_10Label.Text = "$k$";
                        app.EEditField_10.Value = double(0.5);
        
                        app.EEditField_11.Visible = 'on';
                        app.EEditField_11Label.Visible = 'on';
                        app.EEditField_11Label.Text = "$\delta$";
                        app.EEditField_11.Value = double(0.9);
        
                        app.EEditField_12.Visible = 'on';
                        app.EEditField_12Label.Visible = 'on';
                        app.EEditField_12Label.Text = "$p$";
                        app.EEditField_12.Value = double(0.5);
        
                        app.EEditField_13.Visible = 'on';
                        app.EEditField_13Label.Visible = 'on';
                        app.EEditField_13Label.Text = "$f$";
                        app.EEditField_13.Value = double(0.98);
        
                        app.EEditField_14.Visible = 'on';
                        app.EEditField_14Label.Visible = 'on';
                        app.EEditField_14Label.Text = "$čas$";
                        app.EEditField_14.Value = double(300);
                    end
        
        
                    if value == "Model 1"
        
                        imageFile = 'resources/images/schema/model1.svg';
                        app.Image.ImageSource = imageFile;
        
                        app.EEditField_13.Visible = 'off';
                        app.EEditField_13Label.Visible = 'off';
                        app.EEditField_14.Visible = 'off';
                        app.EEditField_14Label.Visible = 'off';
                        app.EEditField_15.Visible = 'off';
                        app.EEditField_15Label.Visible = 'off';
                        app.EEditField_16.Visible = 'off';
                        app.EEditField_16Label.Visible = 'off';
                        app.EEditField_17.Visible = 'off';
                        app.EEditField_17Label.Visible = 'off';
                        app.EEditField_18.Visible = 'off';
                        app.EEditField_18Label.Visible = 'off';
                        app.EEditField_19.Visible = 'off';
                        app.EEditField_19Label.Visible = 'off';
                        app.EEditField_20.Visible = 'off';
                        app.EEditField_20Label.Visible = 'off';
                        app.EEditField_21.Visible = 'off';
                        app.EEditField_21Label.Visible = 'off';
                        app.EEditField_22.Visible = 'off';
                        app.EEditField_22Label.Visible = 'off';
                        app.EEditField_23.Visible = 'off';
                        app.EEditField_23Label.Visible = 'off';
                        app.EEditField_24.Visible = 'off';
                        app.EEditField_24Label.Visible = 'off';
                        app.EEditField_25.Visible = 'off';
                        app.EEditField_25Label.Visible = 'off';
                        app.EEditField_26.Visible = 'off';
                        app.EEditField_26Label.Visible = 'off';
                        app.EEditField_27.Visible = 'off';
                        app.EEditField_27Label.Visible = 'off';
                        app.EEditField_28.Visible = 'off';
                        app.EEditField_28Label.Visible = 'off';
                        app.EEditField_29.Visible = 'off';
                        app.EEditField_29Label.Visible = 'off';
                        app.EEditField_30.Visible = 'off';
                        app.EEditField_30Label.Visible = 'off';


                        app.EEditField.Visible = 'on';
                        app.EEditFieldLabel.Visible = 'on';
                        app.EEditFieldLabel.Text = "$S$";
                        app.EEditField.Value = double(980);
        
                        app.EEditField_2.Visible = 'on';
                        app.EEditField_2Label.Visible = 'on';
                        app.EEditField_2Label.Text = "$L_1$";
                        app.EEditField_2.Value = double(0);
        
                        app.EEditField_3.Visible = 'on';
                        app.EEditField_3Label.Visible = 'on';
                        app.EEditField_3Label.Text = "$L_2$";
                        app.EEditField_3.Value = double(0);
        
                        app.EEditField_4.Visible = 'on';
                        app.EEditField_4Label.Visible = 'on';
                        app.EEditField_4Label.Text = "$I$";
                        app.EEditField_4.Value = double(20);
        
                        app.EEditField_5.Visible = 'on';
                        app.EEditField_5Label.Visible = 'on';
                        app.EEditField_5Label.Text = "$R$";
                        app.EEditField_5.Value = double(0);
        
                        app.EEditField_6.Visible = 'on';
                        app.EEditField_6Label.Visible = 'on';
                        app.EEditField_6Label.Text = "$\beta$";
                        app.EEditField_6.Value = double(0.0401);
        
                        app.EEditField_7.Visible = 'on';
                        app.EEditField_7Label.Visible = 'on';
                        app.EEditField_7Label.Text = "$p$";
                        app.EEditField_7.Value = double(0.7);
        
                        app.EEditField_8.Visible = 'on';
                        app.EEditField_8Label.Visible = 'on';
                        app.EEditField_8Label.Text = "$\mu$";
                        app.EEditField_8.Value = double(0.01);
        
                        app.EEditField_9.Visible = 'on';
                        app.EEditField_9Label.Visible = 'on';
                        app.EEditField_9Label.Text = "$v_1$";
                        app.EEditField_9.Value = double(0.1);
        
                        app.EEditField_10.Visible = 'on';
                        app.EEditField_10Label.Visible = 'on';
                        app.EEditField_10Label.Text = "$v_2$";
                        app.EEditField_10.Value = double(0.3);
        
                        app.EEditField_11.Visible = 'on';
                        app.EEditField_11Label.Visible = 'on';
                        app.EEditField_11Label.Text = "$\gamma$";
                        app.EEditField_11.Value = double(0.00059);
        
                        app.EEditField_12.Visible = 'on';
                        app.EEditField_12Label.Visible = 'on';
                        app.EEditField_12Label.Text = "$čas$";
                        app.EEditField_12.Value = double(300);
                    end
        
                    if value == "Model 3"
        
                        imageFile = 'resources/images/schema/model3.svg';
                        app.Image.ImageSource = imageFile;
        
                        app.EEditField_19.Visible = 'off';
                        app.EEditField_19Label.Visible = 'off';
                        app.EEditField_20.Visible = 'off';
                        app.EEditField_20Label.Visible = 'off';
                        app.EEditField_21.Visible = 'off';
                        app.EEditField_21Label.Visible = 'off';
                        app.EEditField_22.Visible = 'off';
                        app.EEditField_22Label.Visible = 'off';
                        app.EEditField_23.Visible = 'off';
                        app.EEditField_23Label.Visible = 'off';
                        app.EEditField_24.Visible = 'off';
                        app.EEditField_24Label.Visible = 'off';
                        app.EEditField_25.Visible = 'off';
                        app.EEditField_25Label.Visible = 'off';
                        app.EEditField_26.Visible = 'off';
                        app.EEditField_26Label.Visible = 'off';
                        app.EEditField_27.Visible = 'off';
                        app.EEditField_27Label.Visible = 'off';
                        app.EEditField_28.Visible = 'off';
                        app.EEditField_28Label.Visible = 'off';
                        app.EEditField_29.Visible = 'off';
                        app.EEditField_29Label.Visible = 'off';
                        app.EEditField_30.Visible = 'off';
                        app.EEditField_30Label.Visible = 'off';


                        app.EEditField.Visible = 'on';
                        app.EEditFieldLabel.Visible = 'on';
                        app.EEditFieldLabel.Text = "$N$";
                        app.EEditField.Value = double(1000);
        
                        app.EEditField_2.Visible = 'on';
                        app.EEditField_2Label.Visible = 'on';
                        app.EEditField_2Label.Text = "$L_1$";
                        app.EEditField_2.Value = double(0);
        
                        app.EEditField_3.Visible = 'on';
                        app.EEditField_3Label.Visible = 'on';
                        app.EEditField_3Label.Text = "$L_2$";
                        app.EEditField_3.Value = double(0);
        
                        app.EEditField_4.Visible = 'on';
                        app.EEditField_4Label.Visible = 'on';
                        app.EEditField_4Label.Text = "$I_1$";
                        app.EEditField_4.Value = double(10);
        
                        app.EEditField_5.Visible = 'on';
                        app.EEditField_5Label.Visible = 'on';
                        app.EEditField_5Label.Text = "$I_2$";
                        app.EEditField_5.Value = double(10);
        
                        app.EEditField_6.Visible = 'on';
                        app.EEditField_6Label.Visible = 'on';
                        app.EEditField_6Label.Text = "$R_1$";
                        app.EEditField_6.Value = double(0);
        
                        app.EEditField_7.Visible = 'on';
                        app.EEditField_7Label.Visible = 'on';
                        app.EEditField_7Label.Text = "$R_2$";
                        app.EEditField_7.Value = double(0);
        
                        app.EEditField_8.Visible = 'on';
                        app.EEditField_8Label.Visible = 'on';
                        app.EEditField_8Label.Text = "$p$";
                        app.EEditField_8.Value = double(0.7);
        
                        app.EEditField_9.Visible = 'on';
                        app.EEditField_9Label.Visible = 'on';
                        app.EEditField_9Label.Text = "$v_1$";
                        app.EEditField_9.Value = double(0.1);
        
                        app.EEditField_10.Visible = 'on';
                        app.EEditField_10Label.Visible = 'on';
                        app.EEditField_10Label.Text = "$v_2$";
                        app.EEditField_10.Value = double(0.3);
        
                        app.EEditField_11.Visible = 'on';
                        app.EEditField_11Label.Visible = 'on';
                        app.EEditField_11Label.Text = "$\gamma_1$";
                        app.EEditField_11.Value = double(0.00059);
        
                        app.EEditField_12.Visible = 'on';
                        app.EEditField_12Label.Visible = 'on';
                        app.EEditField_12Label.Text = "$\gamma_2$";
                        app.EEditField_12.Value = double(0.00059);
        
                        app.EEditField_13.Visible = 'on';
                        app.EEditField_13Label.Visible = 'on';
                        app.EEditField_13Label.Text = "$\beta_{11}$";
                        app.EEditField_13.Value = double(0.0401);
        
                        app.EEditField_14.Visible = 'on';
                        app.EEditField_14Label.Visible = 'on';
                        app.EEditField_14Label.Text = "$\beta_{12}$";
                        app.EEditField_14.Value = double(0.0401);
        
                        app.EEditField_15.Visible = 'on';
                        app.EEditField_15Label.Visible = 'on';
                        app.EEditField_15Label.Text = "$\beta_{21}$";
                        app.EEditField_15.Value = double(0.0201);
        
                        app.EEditField_16.Visible = 'on';
                        app.EEditField_16Label.Visible = 'on';
                        app.EEditField_16Label.Text = "$\beta_{22}$";
                        app.EEditField_16.Value = double(0.0201);
        
                        app.EEditField_17.Visible = 'on';
                        app.EEditField_17Label.Visible = 'on';
                        app.EEditField_17Label.Text = "$\mu$";
                        app.EEditField_17.Value = double(0.01);
        
                        app.EEditField_18.Visible = 'on';
                        app.EEditField_18Label.Visible = 'on';
                        app.EEditField_18Label.Text = "$čas$";
                        app.EEditField_18.Value = double(700);
        
                    end
        
                    if value == "Model 2"
        
                        imageFile = 'resources/images/schema/model2.svg';
                        app.Image.ImageSource = imageFile;
        
                        app.EEditField_17.Visible = 'off';
                        app.EEditField_17Label.Visible = 'off';
                        app.EEditField_18.Visible = 'off';
                        app.EEditField_18Label.Visible = 'off';
                        app.EEditField_19.Visible = 'off';
                        app.EEditField_19Label.Visible = 'off';
                        app.EEditField_20.Visible = 'off';
                        app.EEditField_20Label.Visible = 'off';
                        app.EEditField_21.Visible = 'off';
                        app.EEditField_21Label.Visible = 'off';
                        app.EEditField_22.Visible = 'off';
                        app.EEditField_22Label.Visible = 'off';
                        app.EEditField_23.Visible = 'off';
                        app.EEditField_23Label.Visible = 'off';
                        app.EEditField_24.Visible = 'off';
                        app.EEditField_24Label.Visible = 'off';
                        app.EEditField_25.Visible = 'off';
                        app.EEditField_25Label.Visible = 'off';
                        app.EEditField_26.Visible = 'off';
                        app.EEditField_26Label.Visible = 'off';
                        app.EEditField_27.Visible = 'off';
                        app.EEditField_27Label.Visible = 'off';
                        app.EEditField_28.Visible = 'off';
                        app.EEditField_28Label.Visible = 'off';
                        app.EEditField_29.Visible = 'off';
                        app.EEditField_29Label.Visible = 'off';
                        app.EEditField_30.Visible = 'off';
                        app.EEditField_30Label.Visible = 'off';
        
        
                        app.EEditField.Visible = 'on';
                        app.EEditFieldLabel.Visible = 'on';
                        app.EEditFieldLabel.Text = "$N_1$";
                        app.EEditField.Value = double(400);
        
                        app.EEditField_2.Visible = 'on';
                        app.EEditField_2Label.Visible = 'on';
                        app.EEditField_2Label.Text = "$N_2$";
                        app.EEditField_2.Value = double(600);
        
                        app.EEditField_3.Visible = 'on';
                        app.EEditField_3Label.Visible = 'on';
                        app.EEditField_3Label.Text = "$L_1$";
                        app.EEditField_3.Value = double(0);
        
                        app.EEditField_4.Visible = 'on';
                        app.EEditField_4Label.Visible = 'on';
                        app.EEditField_4Label.Text = "$L_2$";
                        app.EEditField_4.Value = double(10);
        
                        app.EEditField_5.Visible = 'on';
                        app.EEditField_5Label.Visible = 'on';
                        app.EEditField_5Label.Text = "$I_1$";
                        app.EEditField_5.Value = double(10);
        
                        app.EEditField_6.Visible = 'on';
                        app.EEditField_6Label.Visible = 'on';
                        app.EEditField_6Label.Text = "$I_2$";
                        app.EEditField_6.Value = double(0);
        
                        app.EEditField_7.Visible = 'on';
                        app.EEditField_7Label.Visible = 'on';
                        app.EEditField_7Label.Text = "$R_1$";
                        app.EEditField_7.Value = double(0);
        
                        app.EEditField_8.Visible = 'on';
                        app.EEditField_8Label.Visible = 'on';
                        app.EEditField_8Label.Text = "$R_2$";
                        app.EEditField_8.Value = double(0.7);
        
                        app.EEditField_9.Visible = 'on';
                        app.EEditField_9Label.Visible = 'on';
                        app.EEditField_9Label.Text = "$v_1$";
                        app.EEditField_9.Value = double(0.1);
        
                        app.EEditField_10.Visible = 'on';
                        app.EEditField_10Label.Visible = 'on';
                        app.EEditField_10Label.Text = "$v_2$";
                        app.EEditField_10.Value = double(0.3);
        
                        app.EEditField_11.Visible = 'on';
                        app.EEditField_11Label.Visible = 'on';
                        app.EEditField_11Label.Text = "$\gamma_1$";
                        app.EEditField_11.Value = double(0.0059);
        
                        app.EEditField_12.Visible = 'on';
                        app.EEditField_12Label.Visible = 'on';
                        app.EEditField_12Label.Text = "$\gamma_2$";
                        app.EEditField_12.Value = double(0.0039);
        
                        app.EEditField_13.Visible = 'on';
                        app.EEditField_13Label.Visible = 'on';
                        app.EEditField_13Label.Text = "$\beta_{1}$";
                        app.EEditField_13.Value = double(0.0401);
        
                        app.EEditField_14.Visible = 'on';
                        app.EEditField_14Label.Visible = 'on';
                        app.EEditField_14Label.Text = "$\beta_{2}$";
                        app.EEditField_14.Value = double(0.0201);
        
        
                        app.EEditField_15.Visible = 'on';
                        app.EEditField_15Label.Visible = 'on';
                        app.EEditField_15Label.Text = "$\mu$";
                        app.EEditField_15.Value = double(0.01);
        
                        app.EEditField_16.Visible = 'on';
                        app.EEditField_16Label.Visible = 'on';
                        app.EEditField_16Label.Text = "$čas$";
                        app.EEditField_16.Value = double(1000);
        
        
                    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 't [čas]')
            ylabel(app.UIAxes, 'N [počet členov]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_generation1 = uiaxes(app.UIFigure);
            title(app.UIAxes_generation1, 'Title')
            xlabel(app.UIAxes_generation1, 't [čas]')
            ylabel(app.UIAxes_generation1, 'N [počet členov]')
            zlabel(app.UIAxes_generation1, 'Z')
            app.UIAxes_generation2 = uiaxes(app.UIFigure);
            title(app.UIAxes_generation2, 'Title')
            xlabel(app.UIAxes_generation2, 't [čas]')
            ylabel(app.UIAxes_generation2, 'N [počet členov]')
            zlabel(app.UIAxes_generation2, 'Z')
            app.ZvotemodelLabel = uilabel(app.UIFigure);
            app.ZvotemodelLabel.Text = 'Zvoľte model:';
            app.DropDownSelectModel = uidropdown(app.UIFigure);
            app.DropDownSelectModel.Items = {'...........', 'Model 1', 'Model 2', 'Model 3', 'Model A', 'Model B', 'Model C', ''};
            app.DropDownSelectModel.ItemsData = {'...........', 'Model 1', 'Model 2', 'Model 3', 'Model A', 'Model B', 'Model C', ''};
            app.DropDownSelectModel.ValueChangedFcn = createCallbackFcn(app, @DropDownSelectModelValueChanged, true);
            app.DropDownSelectModel.Placeholder = '...........';
            app.DropDownSelectModel.Value = '...........';
            app.PrepotaButton = uibutton(app.UIFigure, 'push');
            app.PrepotaButton.ButtonPushedFcn = createCallbackFcn(app, @Callbacks_PrepotaButton, true);
            app.PrepotaButton.Text = 'Prepočítať';
            app.EEditFieldLabel = uilabel(app.UIFigure);
            app.EEditFieldLabel.Interpreter = 'latex';
            app.EEditFieldLabel.Visible = 'off';
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.UIFigure, 'numeric');
            app.EEditField.Visible = 'off';
            app.EEditField_2Label = uilabel(app.UIFigure);
            app.EEditField_2Label.Interpreter = 'latex';
            app.EEditField_2Label.Visible = 'off';
            app.EEditField_2Label.Text = 'E';
            app.EEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_2.Visible = 'off';
            app.EEditField_3Label = uilabel(app.UIFigure);
            app.EEditField_3Label.Interpreter = 'latex';
            app.EEditField_3Label.Visible = 'off';
            app.EEditField_3Label.Text = 'E';
            app.EEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_3.Visible = 'off';
            app.EEditField_4Label = uilabel(app.UIFigure);
            app.EEditField_4Label.Interpreter = 'latex';
            app.EEditField_4Label.Visible = 'off';
            app.EEditField_4Label.Text = 'E';
            app.EEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_4.Visible = 'off';
            app.EEditField_5Label = uilabel(app.UIFigure);
            app.EEditField_5Label.Interpreter = 'latex';
            app.EEditField_5Label.Visible = 'off';
            app.EEditField_5Label.Text = 'E';
            app.EEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_5.Visible = 'off';
            app.EEditField_6Label = uilabel(app.UIFigure);
            app.EEditField_6Label.Interpreter = 'latex';
            app.EEditField_6Label.Visible = 'off';
            app.EEditField_6Label.Text = 'E';
            app.EEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_6.Visible = 'off';
            app.EEditField_7Label = uilabel(app.UIFigure);
            app.EEditField_7Label.Interpreter = 'latex';
            app.EEditField_7Label.Visible = 'off';
            app.EEditField_7Label.Text = 'E';
            app.EEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_7.Visible = 'off';
            app.EEditField_8Label = uilabel(app.UIFigure);
            app.EEditField_8Label.Interpreter = 'latex';
            app.EEditField_8Label.Visible = 'off';
            app.EEditField_8Label.Text = 'E';
            app.EEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_8.Visible = 'off';
            app.EEditField_9Label = uilabel(app.UIFigure);
            app.EEditField_9Label.Interpreter = 'latex';
            app.EEditField_9Label.Visible = 'off';
            app.EEditField_9Label.Text = 'E';
            app.EEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_9.Visible = 'off';
            app.EEditField_10Label = uilabel(app.UIFigure);
            app.EEditField_10Label.Interpreter = 'latex';
            app.EEditField_10Label.Visible = 'off';
            app.EEditField_10Label.Text = 'E';
            app.EEditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_10.Visible = 'off';
            app.EEditField_11Label = uilabel(app.UIFigure);
            app.EEditField_11Label.Interpreter = 'latex';
            app.EEditField_11Label.Visible = 'off';
            app.EEditField_11Label.Text = 'E';
            app.EEditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_11.Visible = 'off';
            app.EEditField_12Label = uilabel(app.UIFigure);
            app.EEditField_12Label.Interpreter = 'latex';
            app.EEditField_12Label.Visible = 'off';
            app.EEditField_12Label.Text = 'E';
            app.EEditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_12.Visible = 'off';
            app.EEditField_13Label = uilabel(app.UIFigure);
            app.EEditField_13Label.Interpreter = 'latex';
            app.EEditField_13Label.Visible = 'off';
            app.EEditField_13Label.Text = 'E';
            app.EEditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_13.Visible = 'off';
            app.EEditField_14Label = uilabel(app.UIFigure);
            app.EEditField_14Label.Interpreter = 'latex';
            app.EEditField_14Label.Visible = 'off';
            app.EEditField_14Label.Text = 'E';
            app.EEditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_14.Visible = 'off';
            app.EEditField_15Label = uilabel(app.UIFigure);
            app.EEditField_15Label.Interpreter = 'latex';
            app.EEditField_15Label.Visible = 'off';
            app.EEditField_15Label.Text = 'E';
            app.EEditField_15 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_15.Visible = 'off';
            app.EEditField_16Label = uilabel(app.UIFigure);
            app.EEditField_16Label.Interpreter = 'latex';
            app.EEditField_16Label.Visible = 'off';
            app.EEditField_16Label.Text = 'E';
            app.EEditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_16.Visible = 'off';
            app.EEditField_17Label = uilabel(app.UIFigure);
            app.EEditField_17Label.Interpreter = 'latex';
            app.EEditField_17Label.Visible = 'off';
            app.EEditField_17Label.Text = 'E';
            app.EEditField_17 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_17.Visible = 'off';
            app.EEditField_18Label = uilabel(app.UIFigure);
            app.EEditField_18Label.Interpreter = 'latex';
            app.EEditField_18Label.Visible = 'off';
            app.EEditField_18Label.Text = 'E';
            app.EEditField_18 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_18.Visible = 'off';
            app.EEditField_19Label = uilabel(app.UIFigure);
            app.EEditField_19Label.Interpreter = 'latex';
            app.EEditField_19Label.Visible = 'off';
            app.EEditField_19Label.Text = 'E';
            app.EEditField_19 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_19.Visible = 'off';
            app.EEditField_20Label = uilabel(app.UIFigure);
            app.EEditField_20Label.Interpreter = 'latex';
            app.EEditField_20Label.Visible = 'off';
            app.EEditField_20Label.Text = 'E';
            app.EEditField_20 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_20.Visible = 'off';
            app.EEditField_21Label = uilabel(app.UIFigure);
            app.EEditField_21Label.Interpreter = 'latex';
            app.EEditField_21Label.Visible = 'off';
            app.EEditField_21Label.Text = 'E';
            app.EEditField_21 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_21.Visible = 'off';
            app.EEditField_22Label = uilabel(app.UIFigure);
            app.EEditField_22Label.Interpreter = 'latex';
            app.EEditField_22Label.Visible = 'off';
            app.EEditField_22Label.Text = 'E';
            app.EEditField_22 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_22.Visible = 'off';
            app.EEditField_23Label = uilabel(app.UIFigure);
            app.EEditField_23Label.Interpreter = 'latex';
            app.EEditField_23Label.Visible = 'off';
            app.EEditField_23Label.Text = 'E';
            app.EEditField_23 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_23.Visible = 'off';
            app.EEditField_24Label = uilabel(app.UIFigure);
            app.EEditField_24Label.Interpreter = 'latex';
            app.EEditField_24Label.Visible = 'off';
            app.EEditField_24Label.Text = 'E';
            app.EEditField_24 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_24.Visible = 'off';
            app.EEditField_25Label = uilabel(app.UIFigure);
            app.EEditField_25Label.Interpreter = 'latex';
            app.EEditField_25Label.Visible = 'off';
            app.EEditField_25Label.Text = 'E';
            app.EEditField_25 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_25.Visible = 'off';
            app.EEditField_26Label = uilabel(app.UIFigure);
            app.EEditField_26Label.Interpreter = 'latex';
            app.EEditField_26Label.Visible = 'off';
            app.EEditField_26Label.Text = 'E';
            app.EEditField_26 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_26.Visible = 'off';
            app.EEditField_27Label = uilabel(app.UIFigure);
            app.EEditField_27Label.Interpreter = 'latex';
            app.EEditField_27Label.Visible = 'off';
            app.EEditField_27Label.Text = 'E';
            app.EEditField_27 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_27.Visible = 'off';
            app.EEditField_28Label = uilabel(app.UIFigure);
            app.EEditField_28Label.Interpreter = 'latex';
            app.EEditField_28Label.Visible = 'off';
            app.EEditField_28Label.Text = 'E';
            app.EEditField_28 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_28.Visible = 'off';
            app.EEditField_29Label = uilabel(app.UIFigure);
            app.EEditField_29Label.Interpreter = 'latex';
            app.EEditField_29Label.Visible = 'off';
            app.EEditField_29Label.Text = 'E';
            app.EEditField_29 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_29.Visible = 'off';
            app.EEditField_30Label = uilabel(app.UIFigure);
            app.EEditField_30Label.Interpreter = 'latex';
            app.EEditField_30Label.Visible = 'off';
            app.EEditField_30Label.Text = 'E';
            app.EEditField_30 = uieditfield(app.UIFigure, 'numeric');
            app.EEditField_30.Visible = 'off';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Visible = 'off';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Interpreter = 'latex';
            app.EditFieldLabel.Visible = 'off';
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Visible = 'off';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Visible = 'off';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Interpreter = 'latex';
            app.EditField_2Label.Visible = 'off';
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Interpreter = 'latex';
            app.EditField_3Label.Visible = 'off';
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Visible = 'off';
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Interpreter = 'latex';
            app.EditField_4Label.Visible = 'off';
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Visible = 'off';
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Interpreter = 'latex';
            app.EditField_5Label.Visible = 'off';
            app.EditField_5Label.Text = 'Edit Field';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Visible = 'off';
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Interpreter = 'latex';
            app.EditField_6Label.Visible = 'off';
            app.EditField_6Label.Text = 'Edit Field';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Visible = 'off';
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Interpreter = 'latex';
            app.EditField_7Label.Visible = 'off';
            app.EditField_7Label.Text = 'Edit Field';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Visible = 'off';
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Interpreter = 'latex';
            app.EditField_8Label.Visible = 'off';
            app.EditField_8Label.Text = 'Edit Field';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Visible = 'off';
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Interpreter = 'latex';
            app.EditField_9Label.Visible = 'off';
            app.EditField_9Label.Text = 'Edit Field';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.Visible = 'off';
            app.EditField_10Label = uilabel(app.UIFigure);
            app.EditField_10Label.Interpreter = 'latex';
            app.EditField_10Label.Visible = 'off';
            app.EditField_10Label.Text = 'Edit Field';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.Visible = 'off';
            app.EditField_11Label = uilabel(app.UIFigure);
            app.EditField_11Label.Interpreter = 'latex';
            app.EditField_11Label.Visible = 'off';
            app.EditField_11Label.Text = 'Edit Field';
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.Visible = 'off';
            app.EditField_12Label = uilabel(app.UIFigure);
            app.EditField_12Label.Interpreter = 'latex';
            app.EditField_12Label.Visible = 'off';
            app.EditField_12Label.Text = 'Edit Field';
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.Visible = 'off';
            app.EditField_13Label = uilabel(app.UIFigure);
            app.EditField_13Label.Interpreter = 'latex';
            app.EditField_13Label.Visible = 'off';
            app.EditField_13Label.Text = 'Edit Field';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulacie_epidemiologickych_modelov

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end